classdef PowerDAB_initial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CalculateButton                matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        MacroparametersLabel           matlab.ui.control.Label
        SemiconductorsLabel            matlab.ui.control.Label
        InductorLabel                  matlab.ui.control.Label
        TransformerLabel               matlab.ui.control.Label
        DCHVVLabel                     matlab.ui.control.Label
        DCLVVLabel                     matlab.ui.control.Label
        SwitchingfreqHzLabel           matlab.ui.control.Label
        DeadtimesLabel                 matlab.ui.control.Label
        ReverseVdropHVVLabel           matlab.ui.control.Label
        ReverseVdropLVVLabel           matlab.ui.control.Label
        OnresistanceHVohmLabel         matlab.ui.control.Label
        OnresistanceLVohmLabel         matlab.ui.control.Label
        CossHVFLabel                   matlab.ui.control.Label
        CossLVFLabel                   matlab.ui.control.Label
        WindingresistanceohmLabel      matlab.ui.control.Label
        InductanceHLabel               matlab.ui.control.Label
        TurnsHVLabel                   matlab.ui.control.Label
        TurnsLVLabel                   matlab.ui.control.Label
        HVwindingresistanceohmLabel    matlab.ui.control.Label
        LVwindingresistanceohmLabel    matlab.ui.control.Label
        HVwindingleakageinductanceHLabel  matlab.ui.control.Label
        LVwindingleakageinductanceHLabel  matlab.ui.control.Label
        NoofmodulesLabel               matlab.ui.control.Label
        SingleLabel                    matlab.ui.control.Label
        UnifiedphaseshiftLabel         matlab.ui.control.Label
        HVPowerWLabel                  matlab.ui.control.Label
        LVPowerWLabel                  matlab.ui.control.Label
        TotallossWLabel                matlab.ui.control.Label
        EfficiencyLabel                matlab.ui.control.Label
        ModularizationLabel            matlab.ui.control.Label
        CalculationLabel               matlab.ui.control.Label
        CellconfigurationLabel         matlab.ui.control.Label
        MultipleLabel                  matlab.ui.control.Label
        RangeofunifiedphaseshiftLabel  matlab.ui.control.Label
        StartLabel                     matlab.ui.control.Label
        StopLabel                      matlab.ui.control.Label
        StepLabel                      matlab.ui.control.Label
        GeneratePlotButton             matlab.ui.control.Button
        psDefinitionofmodulestructurescanbefoundinthepaperLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        VHEditFieldLabel               matlab.ui.control.Label
        VHEditField                    matlab.ui.control.NumericEditField
        VLEditFieldLabel               matlab.ui.control.Label
        VLEditField                    matlab.ui.control.NumericEditField
        fsEditFieldLabel               matlab.ui.control.Label
        fsEditField                    matlab.ui.control.NumericEditField
        TdEditFieldLabel               matlab.ui.control.Label
        TdEditField                    matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        VdHEditFieldLabel              matlab.ui.control.Label
        VdHEditField                   matlab.ui.control.NumericEditField
        VdLEditFieldLabel              matlab.ui.control.Label
        VdLEditField                   matlab.ui.control.NumericEditField
        RonHEditFieldLabel             matlab.ui.control.Label
        RonHEditField                  matlab.ui.control.NumericEditField
        RonLEditFieldLabel             matlab.ui.control.Label
        RonLEditField                  matlab.ui.control.NumericEditField
        RLeEditFieldLabel              matlab.ui.control.Label
        RLeEditField                   matlab.ui.control.NumericEditField
        LeEditFieldLabel               matlab.ui.control.Label
        LeEditField                    matlab.ui.control.NumericEditField
        NHEditFieldLabel               matlab.ui.control.Label
        NHEditField                    matlab.ui.control.NumericEditField
        NLEditFieldLabel               matlab.ui.control.Label
        NLEditField                    matlab.ui.control.NumericEditField
        RtrpEditFieldLabel             matlab.ui.control.Label
        RtrpEditField                  matlab.ui.control.NumericEditField
        RtrsEditFieldLabel             matlab.ui.control.Label
        RtrsEditField                  matlab.ui.control.NumericEditField
        LlkpEditFieldLabel             matlab.ui.control.Label
        LlkpEditField                  matlab.ui.control.NumericEditField
        LlksEditFieldLabel             matlab.ui.control.Label
        LlksEditField                  matlab.ui.control.NumericEditField
        ModulestructureDropDownLabel   matlab.ui.control.Label
        ModulestructureDropDown        matlab.ui.control.DropDown
        NmEditFieldLabel               matlab.ui.control.Label
        NmEditField                    matlab.ui.control.NumericEditField
        PHVEditFieldLabel              matlab.ui.control.Label
        PHVEditField                   matlab.ui.control.NumericEditField
        PLVEditFieldLabel              matlab.ui.control.Label
        PLVEditField                   matlab.ui.control.NumericEditField
        CossHEditFieldLabel            matlab.ui.control.Label
        CossHEditField                 matlab.ui.control.NumericEditField
        CossLEditFieldLabel            matlab.ui.control.Label
        CossLEditField                 matlab.ui.control.NumericEditField
        lossEditFieldLabel             matlab.ui.control.Label
        lossEditField                  matlab.ui.control.NumericEditField
        etaEditFieldLabel              matlab.ui.control.Label
        etaEditField                   matlab.ui.control.NumericEditField
        dbEditFieldLabel               matlab.ui.control.Label
        dbEditField                    matlab.ui.control.NumericEditField
        dtEditFieldLabel               matlab.ui.control.Label
        dtEditField                    matlab.ui.control.NumericEditField
        dstepEditFieldLabel            matlab.ui.control.Label
        dstepEditField                 matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)


        function [a,b] = posipowervsdmoreron(app,d,Td,fs,V1,V2,VdH,VdL,RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,Vdc1,Vdc2)
            %
            n1=NH/NL;            %Turns ratio of transformer
            %
            %
            % AC part parameters
            Rac=(1*n1*n1*RLe)+(1*Rtrp)+(1*n1*n1*Rtrs);  %Total resistance of AC part
            Lac=(1*n1*n1*Le)+(1*Llkp)+(1*n1*n1*Llks);  %Total inductance of AC part
            %
            Ts=1/fs;
            %
            %All parameters used in little d region are denoted with 'Li'
            Vac11Li=V1+(2*VdH);
            Vac12Li=Vac11Li;
            Vac22Li=-n1*(V2+(2*VdL));   %Vac2 during interval 2
            Vac23Li=Vac22Li;
            %
            Deltat1Li=(d*Ts/2);
            Deltat2Li=Td-(d*Ts/2);
            Deltat3Li=(d*Ts/2);
            FLi=(Ts/2)-(d*Ts/2)-Td;
            J1Li=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2Li=Rac/Lac;
            J3Li=(Rac+(2*nn*RonH))/Lac;
            J4Li=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5Li=J4Li;
            M1Li=(nn*Vac11Li+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2Li=(nn*Vac12Li-mm*Vac22Li)/Rac;
            M3Li=(nn*V1-mm*Vac23Li)/(Rac+(2*nn*RonH));
            M4Li=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5Li=M4Li;
            %
            HLi=((M4Li-M3Li)*(exp((J3Li*Deltat3Li)+(J2Li*Deltat2Li)+(J1Li*Deltat1Li))))+((M3Li-M2Li)*(exp((J2Li*Deltat2Li)+(J1Li*Deltat1Li))))+((M2Li-M1Li)*(exp(J1Li*Deltat1Li)))+M1Li;
            WLi=M4Li*(exp((J3Li*Deltat3Li)+(J2Li*Deltat2Li)+(J1Li*Deltat1Li)));
            AALi=(HLi+M5Li)/(M5Li+(WLi*(exp(J4Li*FLi))));
            Deltat5Li=(-1/J5Li)*(log(AALi));
            Deltat4Li=FLi-Deltat5Li;
            IL0Li=-M5Li*(1-(exp(-J5Li*Deltat5Li)));
            IL1Li=(IL0Li*(exp(-J1Li*Deltat1Li)))+(M1Li*(1-(exp(-J1Li*Deltat1Li))));
            IL2Li=(IL1Li*(exp(-J2Li*Deltat2Li)))+(M2Li*(1-(exp(-J2Li*Deltat2Li))));
            IL3Li=(IL2Li*(exp(-J3Li*Deltat3Li)))+(M3Li*(1-(exp(-J3Li*Deltat3Li))));
            IL4Li=0;
            %
            dboundary0=(Td)/(Ts/2);
            %
            Integration1Li=(M1Li*Deltat1Li)+(IL0Li-M1Li)*(-1/J1Li)*((exp(-J1Li*Deltat1Li))-1);  %Integration of iL during interval 1
            Integration2Li=(M2Li*Deltat2Li)+(IL1Li-M2Li)*(-1/J2Li)*((exp(-J2Li*Deltat2Li))-1);  %Integration of iL during interval 2
            Integration3Li=(M3Li*Deltat3Li)+(IL2Li-M3Li)*(-1/J3Li)*((exp(-J3Li*Deltat3Li))-1);  %Integration of iL during interval 3
            Integration4Li=(M4Li*Deltat4Li)+(IL3Li-M4Li)*(-1/J4Li)*((exp(-J4Li*Deltat4Li))-1);   %Integration of iL during interval 4
            Integration5Li=(M5Li*Deltat5Li)+(IL4Li-M5Li)*(-1/J5Li)*((exp(-J5Li*Deltat5Li))-1);   %Integration of iL during interval 4
            %
            P1Li=(2*kin*Vdc1/Ts)*(Integration1Li+Integration2Li+Integration3Li+Integration4Li+Integration5Li);   %input power of high voltage port
            P2Li=(2*ko*Vdc2*n1/Ts)*(-Integration1Li-Integration2Li-Integration3Li+Integration4Li+Integration5Li);   %output power of low voltage port
            %Pc=(n*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %All parameters used in small d region are denoted with 's'
            Vac11s=V1+(2*VdH);
            Vac23s=-n1*(V2+(2*VdL));   %Vac2 during interval 2
            %
            Deltat1s=Td;
            Deltat2s=(d*Ts/2)-Td;
            Deltat3s=Td;
            Fs=(Ts/2)-(d*Ts/2)-Td;
            J1s=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2s=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3s=(Rac+(2*nn*RonH))/Lac;
            J4s=J2s;
            J5s=J2s;
            M1s=(nn*Vac11s+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2s=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3s=(nn*V1-mm*Vac23s)/(Rac+(2*nn*RonH));
            M4s=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5s=M4s;
            %
            Hs=((M4s-M3s)*(exp(J2s*Deltat2s))*(exp(J3s*Deltat3s)))+((M3s-M2s)*(exp(J2s*Deltat2s)))+M2s-M1s*(1-(exp(-J1s*Deltat1s)));
            Ws=M4s*(exp(J2s*Deltat2s))*(exp(J3s*Deltat3s));
            AAs=(Hs+(M5s*(exp(-J1s*Deltat1s))))/((M5s*(exp(-J1s*Deltat1s)))+(Ws*(exp(J4s*Fs))));
            Deltat5s=(-1/J5s)*(log(AAs));
            Deltat4s=Fs-Deltat5s;
            IL0s=-M5s*(1-(exp(-J5s*Deltat5s)));
            IL1s=(IL0s*(exp(-J1s*Deltat1s)))+(M1s*(1-(exp(-J1s*Deltat1s))));
            IL2s=(IL1s*(exp(-J2s*Deltat2s)))+(M2s*(1-(exp(-J2s*Deltat2s))));
            IL3s=(IL2s*(exp(-J3s*Deltat3s)))+(M3s*(1-(exp(-J3s*Deltat3s))));
            Yb=(-1/J2s)*(log((M3s-M2s-(M3s*(exp(J3s*Deltat3s))))/(IL1s-M2s)));
            %
            dboundary1=(2/Ts)*(Yb+Td);    %Boundary of d, exceeds which the phase drift decreases
            %
            Integration1s=(M1s*Deltat1s)+(IL0s-M1s)*(-1/J1s)*((exp(-J1s*Deltat1s))-1);  %Integration of iL during interval 1
            Integration2s=(M2s*Deltat2s)+(IL1s-M2s)*(-1/J2s)*((exp(-J2s*Deltat2s))-1);  %Integration of iL during interval 2
            Integration3s=(M3s*Deltat3s)+(IL2s-M3s)*(-1/J3s)*((exp(-J3s*Deltat3s))-1);  %Integration of iL during interval 3
            Integration4s=(M4s*Deltat4s)+(IL3s-M4s)*(-1/J4s)*((exp(-J4s*Deltat4s))-1);   %Integration of iL during interval 4
            Integration5s=(M5s*Deltat5s)+(-M5s)*(-1/J5s)*((exp(-J5s*Deltat5s))-1);   %Integration of iL during interval 4
            %
            P1s=(2*kin*Vdc1/Ts)*(Integration1s+Integration2s+Integration3s+Integration4s+Integration5s);   %input power of high voltage port
            P2s=(2*ko*Vdc2*n1/Ts)*(-Integration1s-Integration2s-Integration3s+Integration4s+Integration5s);   %output power of low voltage port
            %done
            %All parameters used in middle d region are denoted with 'm'
            Vac11m=V1+(2*VdH);
            Vac23m=-n1*(V2+(2*VdL));   %Vac2 during interval 3
            Vac24m=n1*(V2+(2*VdL));
            %
            Deltat1m=Td;
            Deltat2m=(d*Ts/2)-Td;
            Deltat5m=(Ts/2)-(d*Ts/2)-Td;
            Fm=Td;
            J1m=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2m=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3m=(Rac+(2*nn*RonH))/Lac;
            J4m=J3m;
            J5m=J2m;
            M1m=(nn*Vac11m+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2m=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3m=(nn*V1-mm*Vac23m)/(Rac+(2*nn*RonH));
            M4m=(nn*V1-mm*Vac24m)/(Rac+(2*nn*RonH));
            M5m=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Hm=(exp(J5m*Deltat5m))*(((exp(J1m*Deltat1m))*((M3m-M2m)*(exp(J2m*Deltat2m))+M2m))-(M1m*(exp(J1m*Deltat1m)))+M1m+(M5m*(1-(exp(-J5m*Deltat5m)))));
            Wm=M3m*(exp(J2m*Deltat2m))*(exp(J1m*Deltat1m))*(exp(J5m*Deltat5m));
            AAm=(Hm+M4m)/(M4m+(Wm*(exp(J3m*Fm))));
            Deltat4m=(-1/J3m)*(log(AAm));
            Deltat3m=Fm-Deltat4m;
            IL0m=-((M4m*(1-(exp(-J4m*Deltat4m)))*(exp(-J5m*Deltat5m)))+(M5m*(1-(exp(-J5m*Deltat5m)))));
            IL1m=(IL0m*(exp(-J1m*Deltat1m)))+(M1m*(1-(exp(-J1m*Deltat1m))));
            IL2m=(IL1m*(exp(-J2m*Deltat2m)))+(M2m*(1-(exp(-J2m*Deltat2m))));
            IL3m=0;
            IL4m=(M4m*(1-(exp(-J4m*Deltat4m))));
            %
            Integration1m=(M1m*Deltat1m)+(IL0m-M1m)*(-1/J1m)*((exp(-J1m*Deltat1m))-1);  %Integration of iL during interval 1
            Integration2m=(M2m*Deltat2m)+(IL1m-M2m)*(-1/J2m)*((exp(-J2m*Deltat2m))-1);  %Integration of iL during interval 2
            Integration3m=(M3m*Deltat3m)+(IL2m-M3m)*(-1/J3m)*((exp(-J3m*Deltat3m))-1);  %Integration of iL during interval 3
            Integration4m=(M4m*Deltat4m)+(IL3m-M4m)*(-1/J4m)*((exp(-J4m*Deltat4m))-1);   %Integration of iL during interval 4
            Integration5m=(M5m*Deltat5m)+(IL4m-M5m)*(-1/J5m)*((exp(-J5m*Deltat5m))-1);   %Integration of iL during interval 5
            %
            P1m=(2*kin*Vdc1/Ts)*(Integration1m+Integration2m+Integration3m+Integration4m+Integration5m);   %input power of high voltage port
            P2m=(2*ko*Vdc2*n1/Ts)*(-Integration1m-Integration2m-Integration3m+Integration4m+Integration5m);   %output power of low voltage port
            %done
            %
            %All parameters used in large d region are denoted with 'L'
            Vac11L=V1+(2*VdH);
            Vac24L=n1*(V2+(2*VdL));
            %
            Deltat1L=Td;
            Deltat4L=Td;
            Deltat5L=(Ts/2)-(d*Ts/2)-Td;
            FL=(d*Ts/2)-Td;
            J1L=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2L=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3L=J2L;
            J4L=(Rac+(2*nn*RonH))/Lac;
            J5L=J2L;
            M1L=(nn*Vac11L+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2L=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3L=M2L;
            M4L=(nn*V1-mm*Vac24L)/(Rac+(2*nn*RonH));
            M5L=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            HL=(M4L*(exp(J4L*Deltat4L)))-M4L+(M5L*(exp(J5L*Deltat5L))*(exp(J4L*Deltat4L)))-(M5L*(exp(J4L*Deltat4L)));
            WL=(exp(J5L*Deltat5L))*(exp(J4L*Deltat4L));
            QL=M2L-(M1L*(1-(exp(-J1L*Deltat1L))))+(((HL+M3L)*(exp(-J1L*Deltat1L)))/WL);
            AAL=QL/(((M3L/WL)*(exp(-J1L*Deltat1L)))+(M2L*(exp(J2L*FL))));
            Deltat3L=(-1/J2L)*(log(AAL));
            Deltat2L=FL-Deltat3L;
            IL0L=-((HL+M3L-(M3L*(exp(-J3L*Deltat3L))))/WL);
            IL1L=(IL0L*(exp(-J1L*Deltat1L)))+(M1L*(1-(exp(-J1L*Deltat1L))));
            IL2L=0;
            IL3L=(IL2L*(exp(-J3L*Deltat3L)))+(M3L*(1-(exp(-J3L*Deltat3L))));
            IL4L=(IL3L*(exp(-J4L*Deltat4L)))+(M4L*(1-(exp(-J4L*Deltat4L))));
            Xb=(1/J2L)*(log((M2L-IL1L)/(M2L)));
            %
            dboundary2=(2/Ts)*(Xb+Td);     %Boundary of d, exceeds which the phase drift vanishes
            %
            Integration1L=(M1L*Deltat1L)+(IL0L-M1L)*(-1/J1L)*((exp(-J1L*Deltat1L))-1);  %Integration of iL during interval 1
            Integration2L=(M2L*Deltat2L)+(IL1L-M2L)*(-1/J2L)*((exp(-J2L*Deltat2L))-1);  %Integration of iL during interval 2
            Integration3L=(M3L*Deltat3L)+(IL2L-M3L)*(-1/J3L)*((exp(-J3L*Deltat3L))-1);  %Integration of iL during interval 3
            Integration4L=(M4L*Deltat4L)+(IL3L-M4L)*(-1/J4L)*((exp(-J4L*Deltat4L))-1);   %Integration of iL during interval 4
            Integration5L=(M5L*Deltat5L)+(IL4L-M5L)*(-1/J5L)*((exp(-J5L*Deltat5L))-1);   %Integration of iL during interval 5
            %
            P1L=(2*kin*Vdc1/Ts)*(Integration1L+Integration2L+Integration3L+Integration4L+Integration5L);   %input power of high voltage port
            P2L=(2*ko*Vdc2*n1/Ts)*(-Integration1L-Integration2L-Integration3L+Integration4L+Integration5L);   %output power of low voltage port
            %c=Pc;
            %done
            if d<dboundary0
                a=P1Li;
                b=P2Li;
            elseif d>=dboundary0 && d<dboundary1    
                a=P1s;
                b=P2s;
            elseif d>=dboundary1 && d<dboundary2
                a=P1m;
                b=P2m;
            else
                a=P1L;
                b=P2L;
            end
        end
        
        function [a,b] = posipowervsdlessron(app,d,Td,fs,V1,V2,VdH,VdL,RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,Vdc1,Vdc2)
            n1=NH/NL;            %Turns ratio of transformer
            %
            %
            % AC part parameters
            Rac=(1*n1*n1*RLe)+(1*Rtrp)+(1*n1*n1*Rtrs);  %Total resistance of AC part
            Lac=(1*n1*n1*Le)+(1*Llkp)+(1*n1*n1*Llks);  %Total inductance of AC part
            % 
            % control parameters
            %
            Ts=1/fs;
            %
            %All parameters used in little d 3 region are denoted with 'L3'
            Vac11L3=-(V1+(2*VdH));
            Vac12L3=Vac11L3;
            Vac22L3=n1*(V2+(2*VdL));   %Vac2 during interval 2
            Vac13L3=0;
            Vac23L3=Vac22L3;
            Vac14L3=0;
            Vac24L3=0;
            Vac15L3=0;
            Vac25L3=0;
            %
            J1L3=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2L3=Rac/Lac;
            J3L3=(Rac+(2*nn*RonH))/Lac;
            J4L3=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5L3=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1L3=(nn*Vac11L3+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2L3=(nn*Vac12L3-mm*Vac22L3)/Rac;
            M3L3=(nn*V1-mm*Vac23L3)/(Rac+(2*nn*RonH));
            M4L3=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5L3=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1L3=(d*Ts/2);
            Deltat2L3=Td-(d*Ts/2);
            Deltat3L3=(d*Ts/2);
            F1L3=(Ts/2)-Td-(d*Ts/2);
            F2L3=exp(-J1L3*Deltat1L3);
            F3L3=M1L3*(1-(exp(-J1L3*Deltat1L3)));
            F4L3=exp(-J2L3*Deltat2L3);
            F5L3=M2L3*(1-(exp(-J2L3*Deltat2L3)));
            F6L3=exp(-J3L3*Deltat3L3);
            F7L3=M3L3*(1-(exp(-J3L3*Deltat3L3)));
            %
            H1L3=M5L3*F2L3*F4L3*F6L3*exp(-J5L3*F1L3);
            H2L3=-M5L3*F2L3*F4L3*F6L3+F3L3*F4L3*F6L3+F5L3*F6L3+F7L3;
            %
            Deltat4L3=(-1/J4L3)*log((H1L3+M4L3)/(M4L3-H2L3));
            Deltat5L3=F1L3-Deltat4L3;
            %
            IL0L3=-M5L3*(1-(exp(-J5L3*Deltat5L3)));
            IL1L3=(IL0L3*(exp(-J1L3*Deltat1L3)))+(M1L3*(1-(exp(-J1L3*Deltat1L3))));
            IL2L3=(IL1L3*(exp(-J2L3*Deltat2L3)))+(M2L3*(1-(exp(-J2L3*Deltat2L3))));
            IL3L3=(IL2L3*(exp(-J3L3*Deltat3L3)))+(M3L3*(1-(exp(-J3L3*Deltat3L3))));
            IL4L3=0;
            IL5L3=M5L3*(1-(exp(-J5L3*Deltat5L3)));
            %
            Integration1L3=(M1L3*Deltat1L3)+(IL0L3-M1L3)*(-1/J1L3)*((exp(-J1L3*Deltat1L3))-1);  %Integration of iL during interval 1
            Integration2L3=(M2L3*Deltat2L3)+(IL1L3-M2L3)*(-1/J2L3)*((exp(-J2L3*Deltat2L3))-1);  %Integration of iL during interval 2
            Integration3L3=(M3L3*Deltat3L3)+(IL2L3-M3L3)*(-1/J3L3)*((exp(-J3L3*Deltat3L3))-1);  %Integration of iL during interval 3
            Integration4L3=(M4L3*Deltat4L3)+(IL3L3-M4L3)*(-1/J4L3)*((exp(-J4L3*Deltat4L3))-1);   %Integration of iL during interval 4
            Integration5L3=(M5L3*Deltat5L3)+(IL4L3-M5L3)*(-1/J5L3)*((exp(-J5L3*Deltat5L3))-1);   %Integration of iL during interval 4
            %
            P1L3=(2*kin*Vdc1/Ts)*(-Integration1L3-Integration2L3+Integration3L3+Integration4L3+Integration5L3);   %input power of high voltage port
            P2L3=(2*ko*Vdc2*n1/Ts)*(-Integration1L3+Integration2L3+Integration3L3+Integration4L3+Integration5L3);   %output power of low voltage port
            %Pc=(n*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %All parameters used in small d region are denoted with 'S'
            %
            Vac11S=-(V1+(2*VdH));
            Vac21S=0;
            Vac12S=0;
            Vac22S=0;   %Vac2 during interval 2
            Vac13S=0;
            Vac23S=n1*(V2+(2*VdL));
            Vac14S=0;
            Vac24S=0;
            Vac15S=0;
            Vac25S=0;
            %
            J1S=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3S=(Rac+(2*nn*RonH))/Lac;
            J4S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1S=(nn*Vac11S+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2S=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3S=(nn*V1-mm*Vac23S)/(Rac+(2*nn*RonH));
            M4S=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5S=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1S=Td;
            Deltat2S=(d*Ts/2)-Td;
            Deltat3S=Td;
            F1S=(Ts/2)-Td-(d*Ts/2);
            F2S=exp(-J1S*Deltat1S);
            F3S=M1S*(1-(exp(-J1S*Deltat1S)));
            F4S=exp(-J2S*Deltat2S);
            F5S=M2S*(1-(exp(-J2S*Deltat2S)));
            F6S=exp(-J3S*Deltat3S);
            F7S=M3S*(1-(exp(-J3S*Deltat3S)));
            %
            H1S=M5S*F2S*F4S*F6S*exp(-J5S*F1S);
            H2S=-M5S*F2S*F4S*F6S+F3S*F4S*F6S+F5S*F6S+F7S;
            %
            Deltat4S=(-1/J4S)*log((H1S+M4S)/(M4S-H2S));
            Deltat5S=F1S-Deltat4S;
            %
            IL0S=-M5S*(1-(exp(-J5S*Deltat5S)));
            IL1S=(IL0S*(exp(-J1S*Deltat1S)))+(M1S*(1-(exp(-J1S*Deltat1S))));
            IL2S=(IL1S*(exp(-J2S*Deltat2S)))+(M2S*(1-(exp(-J2S*Deltat2S))));
            IL3S=(IL2S*(exp(-J3S*Deltat3S)))+(M3S*(1-(exp(-J3S*Deltat3S))));
            IL4S=(IL3S*(exp(-J4S*Deltat4S)))+(M4S*(1-(exp(-J4S*Deltat4S))));
            IL5S=M5S*(1-(exp(-J5S*Deltat5S)));
            %
            Integration1S=(M1S*Deltat1S)+(IL0S-M1S)*(-1/J1S)*((exp(-J1S*Deltat1S))-1);  %Integration of iL during interval 1
            Integration2S=(M2S*Deltat2S)+(IL1S-M2S)*(-1/J2S)*((exp(-J2S*Deltat2S))-1);  %Integration of iL during interval 2
            Integration3S=(M3S*Deltat3S)+(IL2S-M3S)*(-1/J3S)*((exp(-J3S*Deltat3S))-1);  %Integration of iL during interval 3
            Integration4S=(M4S*Deltat4S)+(IL3S-M4S)*(-1/J4S)*((exp(-J4S*Deltat4S))-1);   %Integration of iL during interval 4
            Integration5S=(M5S*Deltat5S)+(IL4S-M5S)*(-1/J5S)*((exp(-J5S*Deltat5S))-1);   %Integration of iL during interval 4
            %
            P1S=(2*kin*Vdc1/Ts)*(-Integration1S+Integration2S+Integration3S+Integration4S+Integration5S);   %input power of high voltage port
            P2S=(2*ko*Vdc2*n1/Ts)*(-Integration1S-Integration2S+Integration3S+Integration4S+Integration5S);   %output power of low voltage port
            %Pc=(n*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %All parameters used in middle d region are denoted with 'M'
            %
            Vac11M=(V1+(2*VdH));
            Vac21M=0;
            Vac12M=-(V1+(2*VdH));
            Vac22M=0;   %Vac2 during interval 2
            Vac13M=0;
            Vac23M=0;
            Vac14M=0;
            Vac24M=n1*(V2+(2*VdL));
            Vac15M=0;
            Vac25M=0;
            %
            J1M=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2M=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J3M=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4M=(Rac+(2*nn*RonH))/Lac;
            J5M=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1M=(nn*Vac11M+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2M=(nn*Vac12M+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M3M=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4M=(nn*V1-mm*Vac24M)/(Rac+(2*nn*RonH));
            M5M=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat3M=(d*Ts/2)-Td;
            Deltat4M=Td;
            Deltat5M=(Ts/2)-Td-(d*Ts/2);
            F1M=exp(-J3M*Deltat3M);
            F2M=M3M*(1-(exp(-J3M*Deltat3M)));
            F3M=exp(-J4M*Deltat4M);
            F4M=M4M*(1-(exp(-J4M*Deltat4M)));
            F5M=exp(-J5M*Deltat5M);
            F6M=M5M*(1-(exp(-J5M*Deltat5M)));
            %
            H1M=M2M*F1M*F3M*F5M*exp(-J2M*Td);
            H2M=-M2M*F1M*F3M*F5M-F2M*F3M*F5M-F4M*F5M-F6M;
            %
            Deltat1M=(-1/J1M)*log((H1M+M1M)/(M1M-H2M));
            Deltat2M=Td-Deltat1M;
            %
            IL2M=(M2M*(1-(exp(-J2M*Deltat2M))));
            IL3M=(IL2M*(exp(-J3M*Deltat3M)))+(M3M*(1-(exp(-J3M*Deltat3M))));
            IL4M=(IL3M*(exp(-J4M*Deltat4M)))+(M4M*(1-(exp(-J4M*Deltat4M))));
            IL5M=(IL4M*(exp(-J5M*Deltat5M)))+M5M*(1-(exp(-J5M*Deltat5M)));
            IL0M=-IL5M;
            IL1M=(IL0M*(exp(-J1M*Deltat1M)))+(M1M*(1-(exp(-J1M*Deltat1M))));
            %
            %
            Integration1M=(M1M*Deltat1M)+(IL0M-M1M)*(-1/J1M)*((exp(-J1M*Deltat1M))-1);  %Integration of iL during interval 1
            Integration2M=(M2M*Deltat2M)+(IL1M-M2M)*(-1/J2M)*((exp(-J2M*Deltat2M))-1);  %Integration of iL during interval 2
            Integration3M=(M3M*Deltat3M)+(IL2M-M3M)*(-1/J3M)*((exp(-J3M*Deltat3M))-1);  %Integration of iL during interval 3
            Integration4M=(M4M*Deltat4M)+(IL3M-M4M)*(-1/J4M)*((exp(-J4M*Deltat4M))-1);   %Integration of iL during interval 4
            Integration5M=(M5M*Deltat5M)+(IL4M-M5M)*(-1/J5M)*((exp(-J5M*Deltat5M))-1);   %Integration of iL during interval 4
            %
            P1M=(2*kin*Vdc1/Ts)*(Integration1M-Integration2M+Integration3M+Integration4M+Integration5M);   %input power of high voltage port
            P2M=(2*ko*Vdc2*n1/Ts)*(-Integration1M-Integration2M-Integration3M+Integration4M+Integration5M);   %output power of low voltage port
            %Pc=(n*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %
            %All parameters used in large d region are denoted with 'La'
            %
            Vac11La=(V1+(2*VdH));
            Vac21La=0;
            Vac12La=0;
            Vac22La=0;   %Vac2 during interval 2
            Vac13La=0;
            Vac23La=0;
            Vac14La=0;
            Vac24La=n1*(V2+(2*VdL));
            Vac15La=0;
            Vac25La=0;
            %
            J1La=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4La=(Rac+(2*nn*RonH))/Lac;
            J5La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1La=(nn*Vac11La+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2La=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3La=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4La=(nn*V1-mm*Vac24La)/(Rac+(2*nn*RonH));
            M5La=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1La=Td;
            Deltat4La=Td;
            Deltat5La=(Ts/2)-Td-(d*Ts/2);
            F1La=(d*Ts/2)-Td;
            F2La=exp(-J1La*Deltat1La);
            F3La=M1La*(1-(exp(-J1La*Deltat1La)));
            F4La=exp(-J4La*Deltat4La);
            F5La=M4La*(1-(exp(-J4La*Deltat4La)));
            F6La=exp(-J5La*Deltat5La);
            F7La=M5La*(1-(exp(-J5La*Deltat5La)));
            %
            H1La=M3La*F2La*F4La*F6La*exp(-J3La*F1La);
            H2La=-M3La*F2La*F4La*F6La-F2La*F5La*F6La-F2La*F7La+F3La;
            %
            Deltat2La=(-1/J2La)*log((H1La+M2La)/(M2La-H2La));
            Deltat3La=F1La-Deltat2La;
            %
            IL3La=(M3La*(1-(exp(-J3La*Deltat3La))));
            IL4La=(IL3La*(exp(-J4La*Deltat4La)))+(M4La*(1-(exp(-J4La*Deltat4La))));
            IL5La=(IL4La*(exp(-J5La*Deltat5La)))+M5La*(1-(exp(-J5La*Deltat5La)));
            IL0La=-IL5La;
            IL1La=(IL0La*(exp(-J1La*Deltat1La)))+(M1La*(1-(exp(-J1La*Deltat1La))));
            IL2La=(IL1La*(exp(-J2La*Deltat2La)))+(M2La*(1-(exp(-J2La*Deltat2La))));
            %
            %
            Integration1La=(M1La*Deltat1La)+(IL0La-M1La)*(-1/J1La)*((exp(-J1La*Deltat1La))-1);  %Integration of iL during interval 1
            Integration2La=(M2La*Deltat2La)+(IL1La-M2La)*(-1/J2La)*((exp(-J2La*Deltat2La))-1);  %Integration of iL during interval 2
            Integration3La=(M3La*Deltat3La)+(IL2La-M3La)*(-1/J3La)*((exp(-J3La*Deltat3La))-1);  %Integration of iL during interval 3
            Integration4La=(M4La*Deltat4La)+(IL3La-M4La)*(-1/J4La)*((exp(-J4La*Deltat4La))-1);   %Integration of iL during interval 4
            Integration5La=(M5La*Deltat5La)+(IL4La-M5La)*(-1/J5La)*((exp(-J5La*Deltat5La))-1);   %Integration of iL during interval 4
            %
            P1La=(2*kin*Vdc1/Ts)*(Integration1La+Integration2La+Integration3La+Integration4La+Integration5La);   %input power of high voltage port
            P2La=(2*ko*Vdc2*n1/Ts)*(-Integration1La-Integration2La-Integration3La+Integration4La+Integration5La);   %output power of low voltage port
            %Pc=(n*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %
            if d<=((2*Td)/Ts)
                a=P1L3;
                b=P2L3;
            elseif d>((2*Td)/Ts) && IL0S>=0
                a=P1S;
                b=P2S;
            elseif d>((2*Td)/Ts) && IL0M<0 && IL2M>0
                a=P1M;
                b=P2M;
            else
                a=P1La;
                b=P2La;
            end
        end
        
        function [a,b] = posipowervsdequalron(app,d,Td,fs,V1,V2,VdH,VdL,RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,Vdc1,Vdc2)
            n1=NH/NL;            %Turns ratio of transformer
            %
            %
            % AC part parameters
            Rac=(1*n1*n1*RLe)+(1*Rtrp)+(1*n1*n1*Rtrs);  %Total resistance of AC part
            Lac=(1*n1*n1*Le)+(1*Llkp)+(1*n1*n1*Llks);  %Total inductance of AC part
            % voltages of two ports
            %
            Ts=1/fs;
            %
            dboundary1=Td/(Ts/2);
            %
            %All parameters used in small d 1 region are denoted with 'S'
            Vac11S=n1*V2;
            Vac21S=n1*V2;
            Vac12S=0;
            Vac22S=0;   %Vac2 during interval 2
            Vac13S=0;
            Vac23S=n1*(V2+(2*VdL));
            Vac14S=0;
            Vac24S=0;
            Vac15S=-n1*V2;
            Vac25S=-n1*V2;
            %
            J1S=0;
            J2S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3S=(Rac+(2*nn*RonH))/Lac;
            J4S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5S=0;
            M1S=0;
            M2S=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3S=(nn*V1-mm*Vac23S)/(Rac+(2*nn*RonH));
            M4S=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5S=0;
            %
            Deltat1S=Td;
            Deltat2S=(d*Ts/2)-Td;
            Deltat3S=Td;
            IL2S=(M2S*(1-(exp(-J2S*Deltat2S))));
            IL3S=(IL2S*(exp(-J3S*Deltat3S)))+(M3S*(1-(exp(-J3S*Deltat3S))));
            Deltat4S=(-1/J4S)*log(M4S/(M4S-IL3S));
            Deltat5S=(Ts/2)-Td-(d*Ts/2)-Deltat4S;
            IL4S=0;
            IL5S=0;
            IL0S=-IL5S;
            IL1S=0;
            F1S=(Ts/2)-Td;
            F2S=M4S*exp(-J4S*F1S);
            F3S=(M2S-M3S)*exp(-J3S*Deltat3S)+M3S;
            F4S=M2S*exp(-J3S*Deltat3S)*exp(J2S*Deltat3S);
            F5S=F4S*exp(-J4S*F1S);
            F6S=F2S-(F3S*exp(-J4S*F1S));
            dboundarys=(1/J4S)*(2/Ts)*log((M4S-F5S)/F6S);
            %
            %Integration1S=(M1S*Deltat1S)+(IL0S-M1S)*(-1/J1S)*((exp(-J1S*Deltat1S))-1);  %Integration of iL during interval 1
            Integration2S=(M2S*Deltat2S)+(IL1S-M2S)*(-1/J2S)*((exp(-J2S*Deltat2S))-1);  %Integration of iL during interval 2
            Integration3S=(M3S*Deltat3S)+(IL2S-M3S)*(-1/J3S)*((exp(-J3S*Deltat3S))-1);  %Integration of iL during interval 3
            Integration4S=(M4S*Deltat4S)+(IL3S-M4S)*(-1/J4S)*((exp(-J4S*Deltat4S))-1);   %Integration of iL during interval 4
            %Integration5S=(M5S*Deltat5S)+(IL4S-M5S)*(-1/J5S)*((exp(-J5S*Deltat5S))-1);   %Integration of iL during interval 4
            %
            P1S=(2*kin*Vdc1/Ts)*(Integration2S+Integration3S+Integration4S);   %input power of high voltage port
            P2S=(2*ko*Vdc2*n1/Ts)*(-Integration2S+Integration3S+Integration4S);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %All parameters used in middle DCM d 1 region are denoted with 'MD'
            %
            Vac11MD=V1+(2*VdH);
            Vac21MD=0;
            Vac12MD=-n1*V2;
            Vac22MD=-n1*V2;   %Vac2 during interval 2
            Vac13MD=0;
            Vac23MD=0;
            Vac14MD=0;
            Vac24MD=n1*(V2+(2*VdL));
            Vac15MD=0;
            Vac25MD=0;
            %
            J1MD=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2MD=0;
            J3MD=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4MD=(Rac+(2*nn*RonH))/Lac;
            J5MD=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1MD=(nn*Vac11MD+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2MD=0;
            M3MD=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4MD=(nn*V1-mm*Vac24MD)/(Rac+(2*nn*RonH));
            M5MD=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat3MD=(d*Ts/2)-Td;
            Deltat4MD=Td;
            Deltat5MD=(Ts/2)-Td-(d*Ts/2);
            IL2MD=0;
            IL3MD=(IL2MD*(exp(-J3MD*Deltat3MD)))+(M3MD*(1-(exp(-J3MD*Deltat3MD))));
            IL4MD=(IL3MD*(exp(-J4MD*Deltat4MD)))+(M4MD*(1-(exp(-J4MD*Deltat4MD))));
            IL5MD=(IL4MD*(exp(-J5MD*Deltat5MD)))+(M5MD*(1-(exp(-J5MD*Deltat5MD))));
            IL0MD=-IL5MD;
            IL1MD=0;
            Deltat1MD=(-1/J1MD)*log(M1MD/(M1MD-IL0MD));
            Deltat2MD=Td-Deltat1MD;
            %
            %
            Integration1MD=(M1MD*Deltat1MD)+(IL0MD-M1MD)*(-1/J1MD)*((exp(-J1MD*Deltat1MD))-1);  %Integration of iL during interval 1
            %Integration2MD=(M2MD*Deltat2MD)+(IL1MD-M2MD)*(-1/J2MD)*((exp(-J2MD*Deltat2MD))-1);  %Integration of iL during interval 2
            Integration3MD=(M3MD*Deltat3MD)+(IL2MD-M3MD)*(-1/J3MD)*((exp(-J3MD*Deltat3MD))-1);  %Integration of iL during interval 3
            Integration4MD=(M4MD*Deltat4MD)+(IL3MD-M4MD)*(-1/J4MD)*((exp(-J4MD*Deltat4MD))-1);   %Integration of iL during interval 4
            Integration5MD=(M5MD*Deltat5MD)+(IL4MD-M5MD)*(-1/J5MD)*((exp(-J5MD*Deltat5MD))-1);   %Integration of iL during interval 4
            %
            P1MD=(2*kin*Vdc1/Ts)*(Integration1MD+Integration3MD+Integration4MD+Integration5MD);   %input power of high voltage port
            P2MD=(2*ko*Vdc2*n1/Ts)*(-Integration1MD-Integration3MD+Integration4MD+Integration5MD);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %All parameters used in middle BCM d 1 region are denoted with 'MB'
            Vac11MB=V1+(2*VdH);
            Vac21MB=0;
            Vac12MB=0;
            Vac22MB=0;   %Vac2 during interval 2
            Vac13MB=0;
            Vac23MB=n1*(V2+(2*VdL));
            Vac14MB=0;
            Vac24MB=0;
            Vac15MB=0;
            Vac25MB=0;
            %
            J1MB=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2MB=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3MB=(Rac+(2*nn*RonH))/Lac;
            J4MB=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5MB=0;
            M1MB=(nn*Vac11MB+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2MB=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3MB=(nn*V1-mm*Vac23MB)/(Rac+(2*nn*RonH));
            M4MB=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5MB=0;
            %
            %
            Deltat1MB=Td;
            Deltat3MB=Td;
            %
            IL0MB=(exp(-J1MB*Deltat1MB)*M1MB-M1MB)/(exp(-J1MB*Deltat1MB));
            %
            F1MB=exp(-J3MB*Deltat3MB);
            F2MB=(M3MB*(1-(exp(-J3MB*Deltat3MB))));
            F3MB=M2MB*exp(J2MB*Td);
            F4MB=(Ts/2)-Td;
            H1MB=(M2MB*F1MB+F2MB-M4MB)*exp(-J4MB*F4MB);
            H2MB=(-F1MB*F3MB*exp(-J4MB*F4MB))+M4MB+IL0MB;
            %
            Deltat2MB=(d*Ts/2)-Td;
            Deltat4MB=(Ts/2)-Td-(d*Ts/2);
            Deltat5MB=0;
            IL1MB=(IL0MB*(exp(-J1MB*Deltat1MB)))+(M1MB*(1-(exp(-J1MB*Deltat1MB))));
            IL2MB=(IL1MB*(exp(-J2MB*Deltat2MB)))+(M2MB*(1-(exp(-J2MB*Deltat2MB))));
            IL3MB=(IL2MB*(exp(-J3MB*Deltat3MB)))+(M3MB*(1-(exp(-J3MB*Deltat3MB))));
            IL4MB=(IL3MB*(exp(-J4MB*Deltat4MB)))+(M4MB*(1-(exp(-J4MB*Deltat4MB))));
            IL5MB=(IL4MB*(exp(-J5MB*Deltat5MB)))+(M5MB*(1-(exp(-J5MB*Deltat5MB))));
            %
            Integration1MB=(M1MB*Deltat1MB)+(IL0MB-M1MB)*(-1/J1MB)*((exp(-J1MB*Deltat1MB))-1);  %Integration of iL during interval 1
            Integration2MB=(M2MB*Deltat2MB)+(IL1MB-M2MB)*(-1/J2MB)*((exp(-J2MB*Deltat2MB))-1);  %Integration of iL during interval 2
            Integration3MB=(M3MB*Deltat3MB)+(IL2MB-M3MB)*(-1/J3MB)*((exp(-J3MB*Deltat3MB))-1);  %Integration of iL during interval 3
            Integration4MB=(M4MB*Deltat4MB)+(IL3MB-M4MB)*(-1/J4MB)*((exp(-J4MB*Deltat4MB))-1);   %Integration of iL during interval 4
            Integration5MB=(M5MB*Deltat5MB)+(IL4MB-M5MB)*(-1/J5MB)*((exp(-J5MB*Deltat5MB))-1);   %Integration of iL during interval 4
            %
            P1MB=(2*kin*Vdc1/Ts)*(Integration1MB+Integration2MB+Integration3MB+Integration4MB);   %input power of high voltage port
            P2MB=(2*ko*Vdc2*n1/Ts)*(-Integration1MB-Integration2MB+Integration3MB+Integration4MB);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %All parameters used in large CCM d 1 region are denoted with 'La'
            %
            Vac11La=(V1+(2*VdH));
            Vac21La=0;
            Vac12La=0;
            Vac22La=0;   %Vac2 during interval 2
            Vac13La=0;
            Vac23La=0;
            Vac14La=0;
            Vac24La=n1*(V2+(2*VdL));
            Vac15La=0;
            Vac25La=0;
            %
            J1La=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J2La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4La=(Rac+(2*nn*RonH))/Lac;
            J5La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1La=(nn*Vac11La+(mm*n1*V2))/(Rac+(2*mm*n1*n1*RonL));
            M2La=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3La=(nn*V1+(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4La=(nn*V1-mm*Vac24La)/(Rac+(2*nn*RonH));
            M5La=(nn*V1-(mm*n1*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1La=Td;
            Deltat4La=Td;
            Deltat5La=(Ts/2)-Td-(d*Ts/2);
            F1La=(d*Ts/2)-Td;
            F2La=exp(-J1La*Deltat1La);
            F3La=M1La*(1-(exp(-J1La*Deltat1La)));
            F4La=exp(-J4La*Deltat4La);
            F5La=M4La*(1-(exp(-J4La*Deltat4La)));
            F6La=exp(-J5La*Deltat5La);
            F7La=M5La*(1-(exp(-J5La*Deltat5La)));
            %
            H1La=M3La*F2La*F4La*F6La*exp(-J3La*F1La);
            H2La=-M3La*F2La*F4La*F6La-F2La*F5La*F6La-F2La*F7La+F3La;
            %
            Deltat2La=(-1/J2La)*log((H1La+M2La)/(M2La-H2La));
            Deltat3La=F1La-Deltat2La;
            %
            IL3La=(M3La*(1-(exp(-J3La*Deltat3La))));
            IL4La=(IL3La*(exp(-J4La*Deltat4La)))+(M4La*(1-(exp(-J4La*Deltat4La))));
            IL5La=(IL4La*(exp(-J5La*Deltat5La)))+M5La*(1-(exp(-J5La*Deltat5La)));
            IL0La=-IL5La;
            IL1La=(IL0La*(exp(-J1La*Deltat1La)))+(M1La*(1-(exp(-J1La*Deltat1La))));
            IL2La=(IL1La*(exp(-J2La*Deltat2La)))+(M2La*(1-(exp(-J2La*Deltat2La))));
            %
            Integration1La=(M1La*Deltat1La)+(IL0La-M1La)*(-1/J1La)*((exp(-J1La*Deltat1La))-1);  %Integration of iL during interval 1
            Integration2La=(M2La*Deltat2La)+(IL1La-M2La)*(-1/J2La)*((exp(-J2La*Deltat2La))-1);  %Integration of iL during interval 2
            Integration3La=(M3La*Deltat3La)+(IL2La-M3La)*(-1/J3La)*((exp(-J3La*Deltat3La))-1);  %Integration of iL during interval 3
            Integration4La=(M4La*Deltat4La)+(IL3La-M4La)*(-1/J4La)*((exp(-J4La*Deltat4La))-1);   %Integration of iL during interval 4
            Integration5La=(M5La*Deltat5La)+(IL4La-M5La)*(-1/J5La)*((exp(-J5La*Deltat5La))-1);   %Integration of iL during interval 4
            %
            P1La=(2*kin*Vdc1/Ts)*(Integration1La+Integration2La+Integration3La+Integration4La+Integration5La);   %input power of high voltage port
            P2La=(2*ko*Vdc2*n1/Ts)*(-Integration1La-Integration2La-Integration3La+Integration4La+Integration5La);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            dboundary=(2/Ts)*(1/J4MB)*log(-H2MB/H1MB);
            %
            if d<dboundary1
                a=0;
                b=0;
            elseif d>=dboundary1 && d<dboundarys
                a=P1S;
                b=P2S;
            elseif d>=dboundarys && d<dboundary
                a=P1MD;
                b=P2MD;
            elseif d==dboundary
                a=P1MB;
                b=P2MB;
            else
                a=P1La;
                b=P2La;
            end
        end
        
        function [a,b] = negapowervsdmoreron(app,d,Td,fs,V1,V2,VdH,VdL,RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,Vdc1,Vdc2)
            n1=NH/NL;            %Turns ratio of transformer
            %
            % AC part parameters
            Rac=(1*n1*n1*RLe)+(1*Rtrp)+(1*n1*n1*Rtrs);  %Total resistance of AC part
            Lac=(1*n1*n1*Le)+(1*Llkp)+(1*n1*n1*Llks);  %Total inductance of AC part
            %
            Ts=1/fs;
            %
            %All parameters used in little d region are denoted with 'Li'
            Vac11Li=n1*(V1+(2*VdL));
            Vac12Li=Vac11Li;
            Vac22Li=-(V2+(2*VdH));   %Vac2 during interval 2
            Vac23Li=Vac22Li;
            %
            Deltat1Li=(d*Ts/2);
            Deltat2Li=Td-(d*Ts/2);
            Deltat3Li=(d*Ts/2);
            FLi=(Ts/2)-(d*Ts/2)-Td;
            J1Li=(Rac+(2*nn*RonH))/Lac;
            J2Li=Rac/Lac;
            J3Li=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J4Li=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5Li=J4Li;
            M1Li=(mm*Vac11Li+(nn*V2))/(Rac+(2*nn*RonH));
            M2Li=(mm*Vac12Li-nn*Vac22Li)/Rac;
            M3Li=((mm*n1*V1)-nn*Vac23Li)/(Rac+(2*mm*n1*n1*RonL));
            M4Li=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5Li=M4Li;
            %
            HLi=((M4Li-M3Li)*(exp((J3Li*Deltat3Li)+(J2Li*Deltat2Li)+(J1Li*Deltat1Li))))+((M3Li-M2Li)*(exp((J2Li*Deltat2Li)+(J1Li*Deltat1Li))))+((M2Li-M1Li)*(exp(J1Li*Deltat1Li)))+M1Li;
            WLi=M4Li*(exp((J3Li*Deltat3Li)+(J2Li*Deltat2Li)+(J1Li*Deltat1Li)));
            AALi=(HLi+M5Li)/(M5Li+(WLi*(exp(J4Li*FLi))));
            Deltat5Li=(-1/J5Li)*(log(AALi));
            Deltat4Li=FLi-Deltat5Li;
            IL0Li=-M5Li*(1-(exp(-J5Li*Deltat5Li)));
            IL1Li=(IL0Li*(exp(-J1Li*Deltat1Li)))+(M1Li*(1-(exp(-J1Li*Deltat1Li))));
            IL2Li=(IL1Li*(exp(-J2Li*Deltat2Li)))+(M2Li*(1-(exp(-J2Li*Deltat2Li))));
            IL3Li=(IL2Li*(exp(-J3Li*Deltat3Li)))+(M3Li*(1-(exp(-J3Li*Deltat3Li))));
            IL4Li=0;
            %
            dboundary0=(Td)/(Ts/2);
            %
            Integration1Li=(M1Li*Deltat1Li)+(IL0Li-M1Li)*(-1/J1Li)*((exp(-J1Li*Deltat1Li))-1);  %Integration of iL during interval 1
            Integration2Li=(M2Li*Deltat2Li)+(IL1Li-M2Li)*(-1/J2Li)*((exp(-J2Li*Deltat2Li))-1);  %Integration of iL during interval 2
            Integration3Li=(M3Li*Deltat3Li)+(IL2Li-M3Li)*(-1/J3Li)*((exp(-J3Li*Deltat3Li))-1);  %Integration of iL during interval 3
            Integration4Li=(M4Li*Deltat4Li)+(IL3Li-M4Li)*(-1/J4Li)*((exp(-J4Li*Deltat4Li))-1);   %Integration of iL during interval 4
            Integration5Li=(M5Li*Deltat5Li)+(IL4Li-M5Li)*(-1/J5Li)*((exp(-J5Li*Deltat5Li))-1);   %Integration of iL during interval 4
            %
            P1Li=(2*ko*Vdc1*n1/Ts)*(Integration1Li+Integration2Li+Integration3Li+Integration4Li+Integration5Li);   %input power of high voltage port
            P2Li=(2*kin*Vdc2/Ts)*(-Integration1Li-Integration2Li-Integration3Li+Integration4Li+Integration5Li);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %All parameters used in small d region are denoted with 's'
            Vac11s=n1*(V1+(2*VdL));
            Vac23s=-(V2+(2*VdH));   %Vac2 during interval 2
            %
            Deltat1s=Td;
            Deltat2s=(d*Ts/2)-Td;
            Deltat3s=Td;
            Fs=(Ts/2)-(d*Ts/2)-Td;
            J1s=(Rac+(2*nn*RonH))/Lac;
            J2s=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3s=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J4s=J2s;
            J5s=J2s;
            M1s=(mm*Vac11s+(nn*V2))/(Rac+(2*nn*RonH));
            M2s=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3s=((mm*n1*V1)-nn*Vac23s)/(Rac+(2*mm*n1*n1*RonL));
            M4s=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5s=M4s;
            %
            Hs=((M4s-M3s)*(exp(J2s*Deltat2s))*(exp(J3s*Deltat3s)))+((M3s-M2s)*(exp(J2s*Deltat2s)))+M2s-M1s*(1-(exp(-J1s*Deltat1s)));
            Ws=M4s*(exp(J2s*Deltat2s))*(exp(J3s*Deltat3s));
            AAs=(Hs+(M5s*(exp(-J1s*Deltat1s))))/((M5s*(exp(-J1s*Deltat1s)))+(Ws*(exp(J4s*Fs))));
            Deltat5s=(-1/J5s)*(log(AAs));
            Deltat4s=Fs-Deltat5s;
            IL0s=-M5s*(1-(exp(-J5s*Deltat5s)));
            IL1s=(IL0s*(exp(-J1s*Deltat1s)))+(M1s*(1-(exp(-J1s*Deltat1s))));
            IL2s=(IL1s*(exp(-J2s*Deltat2s)))+(M2s*(1-(exp(-J2s*Deltat2s))));
            IL3s=(IL2s*(exp(-J3s*Deltat3s)))+(M3s*(1-(exp(-J3s*Deltat3s))));
            Yb=(-1/J2s)*(log((M3s-M2s-(M3s*(exp(J3s*Deltat3s))))/(IL1s-M2s)));
            %
            dboundary1=(2/Ts)*(Yb+Td);    %Boundary of d, exceeds which the phase drift decreases
            %
            Integration1s=(M1s*Deltat1s)+(IL0s-M1s)*(-1/J1s)*((exp(-J1s*Deltat1s))-1);  %Integration of iL during interval 1
            Integration2s=(M2s*Deltat2s)+(IL1s-M2s)*(-1/J2s)*((exp(-J2s*Deltat2s))-1);  %Integration of iL during interval 2
            Integration3s=(M3s*Deltat3s)+(IL2s-M3s)*(-1/J3s)*((exp(-J3s*Deltat3s))-1);  %Integration of iL during interval 3
            Integration4s=(M4s*Deltat4s)+(IL3s-M4s)*(-1/J4s)*((exp(-J4s*Deltat4s))-1);   %Integration of iL during interval 4
            Integration5s=(M5s*Deltat5s)+(-M5s)*(-1/J5s)*((exp(-J5s*Deltat5s))-1);   %Integration of iL during interval 4
            %
            P1s=(2*ko*Vdc1*n1/Ts)*(Integration1s+Integration2s+Integration3s+Integration4s+Integration5s);   %input power of high voltage port
            P2s=(2*kin*Vdc2/Ts)*(-Integration1s-Integration2s-Integration3s+Integration4s+Integration5s);   %output power of low voltage port
            %done
            %All parameters used in middle d region are denoted with 'm'
            Vac11m=n1*(V1+(2*VdL));
            Vac23m=-(V2+(2*VdH));   %Vac2 during interval 3
            Vac24m=(V2+(2*VdH));
            %
            Deltat1m=Td;
            Deltat2m=(d*Ts/2)-Td;
            Deltat5m=(Ts/2)-(d*Ts/2)-Td;
            Fm=Td;
            J1m=(Rac+(2*nn*RonH))/Lac;
            J2m=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3m=(Rac+2*mm*n1*n1*RonL)/Lac;
            J4m=J3m;
            J5m=J2m;
            M1m=(mm*Vac11m+(nn*V2))/(Rac+(2*nn*RonH));
            M2m=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3m=((mm*n1*V1)-nn*Vac23m)/(Rac+(2*mm*n1*n1*RonL));
            M4m=((mm*n1*V1)-nn*Vac24m)/(Rac+(2*mm*n1*n1*RonL));
            M5m=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Hm=(exp(J5m*Deltat5m))*(((exp(J1m*Deltat1m))*((M3m-M2m)*(exp(J2m*Deltat2m))+M2m))-(M1m*(exp(J1m*Deltat1m)))+M1m+(M5m*(1-(exp(-J5m*Deltat5m)))));
            Wm=M3m*(exp(J2m*Deltat2m))*(exp(J1m*Deltat1m))*(exp(J5m*Deltat5m));
            AAm=(Hm+M4m)/(M4m+(Wm*(exp(J3m*Fm))));
            Deltat4m=(-1/J3m)*(log(AAm));
            Deltat3m=Fm-Deltat4m;
            IL0m=-((M4m*(1-(exp(-J4m*Deltat4m)))*(exp(-J5m*Deltat5m)))+(M5m*(1-(exp(-J5m*Deltat5m)))));
            IL1m=(IL0m*(exp(-J1m*Deltat1m)))+(M1m*(1-(exp(-J1m*Deltat1m))));
            IL2m=(IL1m*(exp(-J2m*Deltat2m)))+(M2m*(1-(exp(-J2m*Deltat2m))));
            IL3m=0;
            IL4m=(M4m*(1-(exp(-J4m*Deltat4m))));
            %
            Integration1m=(M1m*Deltat1m)+(IL0m-M1m)*(-1/J1m)*((exp(-J1m*Deltat1m))-1);  %Integration of iL during interval 1
            Integration2m=(M2m*Deltat2m)+(IL1m-M2m)*(-1/J2m)*((exp(-J2m*Deltat2m))-1);  %Integration of iL during interval 2
            Integration3m=(M3m*Deltat3m)+(IL2m-M3m)*(-1/J3m)*((exp(-J3m*Deltat3m))-1);  %Integration of iL during interval 3
            Integration4m=(M4m*Deltat4m)+(IL3m-M4m)*(-1/J4m)*((exp(-J4m*Deltat4m))-1);   %Integration of iL during interval 4
            Integration5m=(M5m*Deltat5m)+(IL4m-M5m)*(-1/J5m)*((exp(-J5m*Deltat5m))-1);   %Integration of iL during interval 5
            %
            P1m=(2*ko*Vdc1*n1/Ts)*(Integration1m+Integration2m+Integration3m+Integration4m+Integration5m);   %input power of high voltage port
            P2m=(2*kin*Vdc2/Ts)*(-Integration1m-Integration2m-Integration3m+Integration4m+Integration5m);   %output power of low voltage port
            %
            %
            %All parameters used in large d region are denoted with 'L'
            Vac11L=n1*(V1+(2*VdL));
            Vac24L=(V2+(2*VdH));
            %
            Deltat1L=Td;
            Deltat4L=Td;
            Deltat5L=(Ts/2)-(d*Ts/2)-Td;
            FL=(d*Ts/2)-Td;
            J1L=(Rac+(2*nn*RonH))/Lac;
            J2L=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3L=J2L;
            J4L=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J5L=J2L;
            M1L=(mm*Vac11L+(nn*V2))/(Rac+(2*nn*RonH));
            M2L=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3L=M2L;
            M4L=((mm*n1*V1)-nn*Vac24L)/(Rac+(2*mm*n1*n1*RonL));
            M5L=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            HL=(M4L*(exp(J4L*Deltat4L)))-M4L+(M5L*(exp(J5L*Deltat5L))*(exp(J4L*Deltat4L)))-(M5L*(exp(J4L*Deltat4L)));
            WL=(exp(J5L*Deltat5L))*(exp(J4L*Deltat4L));
            QL=M2L-(M1L*(1-(exp(-J1L*Deltat1L))))+(((HL+M3L)*(exp(-J1L*Deltat1L)))/WL);
            AAL=QL/(((M3L/WL)*(exp(-J1L*Deltat1L)))+(M2L*(exp(J2L*FL))));
            Deltat3L=(-1/J2L)*(log(AAL));
            Deltat2L=FL-Deltat3L;
            IL0L=-((HL+M3L-(M3L*(exp(-J3L*Deltat3L))))/WL);
            IL1L=(IL0L*(exp(-J1L*Deltat1L)))+(M1L*(1-(exp(-J1L*Deltat1L))));
            IL2L=0;
            IL3L=(IL2L*(exp(-J3L*Deltat3L)))+(M3L*(1-(exp(-J3L*Deltat3L))));
            IL4L=(IL3L*(exp(-J4L*Deltat4L)))+(M4L*(1-(exp(-J4L*Deltat4L))));
            Xb=(1/J2L)*(log((M2L-IL1L)/(M2L)));
            %
            dboundary2=(2/Ts)*(Xb+Td);     %Boundary of d, exceeds which the phase drift vanishes
            %
            Integration1L=(M1L*Deltat1L)+(IL0L-M1L)*(-1/J1L)*((exp(-J1L*Deltat1L))-1);  %Integration of iL during interval 1
            Integration2L=(M2L*Deltat2L)+(IL1L-M2L)*(-1/J2L)*((exp(-J2L*Deltat2L))-1);  %Integration of iL during interval 2
            Integration3L=(M3L*Deltat3L)+(IL2L-M3L)*(-1/J3L)*((exp(-J3L*Deltat3L))-1);  %Integration of iL during interval 3
            Integration4L=(M4L*Deltat4L)+(IL3L-M4L)*(-1/J4L)*((exp(-J4L*Deltat4L))-1);   %Integration of iL during interval 4
            Integration5L=(M5L*Deltat5L)+(IL4L-M5L)*(-1/J5L)*((exp(-J5L*Deltat5L))-1);   %Integration of iL during interval 5
            %
            P1L=(2*ko*Vdc1*n1/Ts)*(Integration1L+Integration2L+Integration3L+Integration4L+Integration5L);   %input power of high voltage port
            P2L=(2*kin*Vdc2/Ts)*(-Integration1L-Integration2L-Integration3L+Integration4L+Integration5L);   %output power of low voltage port
            if d<dboundary0
                a=P1Li;
                b=P2Li;
            elseif d>=dboundary0 && d<dboundary1    
                a=P1s;
                b=P2s;
            elseif d>=dboundary1 && d<dboundary2
                a=P1m;
                b=P2m;
            else
                a=P1L;
                b=P2L;
            end
        end
        
        function [a,b] = negapowervsdlessron(app,d,Td,fs,V1,V2,VdH,VdL,RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,Vdc1,Vdc2)
            n1=NH/NL;            %Turns ratio of transformer
            %
            % AC part parameters
            Rac=(1*n1*n1*RLe)+(1*Rtrp)+(1*n1*n1*Rtrs);  %Total resistance of AC part
            Lac=(1*n1*n1*Le)+(1*Llkp)+(1*n1*n1*Llks);  %Total inductance of AC part
            %
            Ts=1/fs;
            %
            %All parameters used in little d 3 region are denoted with 'L3'
            Vac11L3=-n1*(V1+(2*VdL));
            Vac12L3=Vac11L3;
            Vac22L3=(V2+(2*VdH));   %Vac2 during interval 2
            Vac13L3=0;
            Vac23L3=Vac22L3;
            Vac14L3=0;
            Vac24L3=0;
            Vac15L3=0;
            Vac25L3=0;
            %
            J1L3=(Rac+(2*nn*RonH))/Lac;
            J2L3=Rac/Lac;
            J3L3=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J4L3=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5L3=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1L3=(mm*Vac11L3+(nn*V2))/(Rac+(2*nn*RonH));
            M2L3=(mm*Vac12L3-nn*Vac22L3)/Rac;
            M3L3=((mm*n1*V1)-nn*Vac23L3)/(Rac+(2*mm*n1*n1*RonL));
            M4L3=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5L3=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1L3=(d*Ts/2);
            Deltat2L3=Td-(d*Ts/2);
            Deltat3L3=(d*Ts/2);
            F1L3=(Ts/2)-Td-(d*Ts/2);
            F2L3=exp(-J1L3*Deltat1L3);
            F3L3=M1L3*(1-(exp(-J1L3*Deltat1L3)));
            F4L3=exp(-J2L3*Deltat2L3);
            F5L3=M2L3*(1-(exp(-J2L3*Deltat2L3)));
            F6L3=exp(-J3L3*Deltat3L3);
            F7L3=M3L3*(1-(exp(-J3L3*Deltat3L3)));
            %
            H1L3=M5L3*F2L3*F4L3*F6L3*exp(-J5L3*F1L3);
            H2L3=-M5L3*F2L3*F4L3*F6L3+F3L3*F4L3*F6L3+F5L3*F6L3+F7L3;
            %
            Deltat4L3=(-1/J4L3)*log((H1L3+M4L3)/(M4L3-H2L3));
            Deltat5L3=F1L3-Deltat4L3;
            %
            IL0L3=-M5L3*(1-(exp(-J5L3*Deltat5L3)));
            IL1L3=(IL0L3*(exp(-J1L3*Deltat1L3)))+(M1L3*(1-(exp(-J1L3*Deltat1L3))));
            IL2L3=(IL1L3*(exp(-J2L3*Deltat2L3)))+(M2L3*(1-(exp(-J2L3*Deltat2L3))));
            IL3L3=(IL2L3*(exp(-J3L3*Deltat3L3)))+(M3L3*(1-(exp(-J3L3*Deltat3L3))));
            IL4L3=0;
            IL5L3=M5L3*(1-(exp(-J5L3*Deltat5L3)));
            %
            Integration1L3=(M1L3*Deltat1L3)+(IL0L3-M1L3)*(-1/J1L3)*((exp(-J1L3*Deltat1L3))-1);  %Integration of iL during interval 1
            Integration2L3=(M2L3*Deltat2L3)+(IL1L3-M2L3)*(-1/J2L3)*((exp(-J2L3*Deltat2L3))-1);  %Integration of iL during interval 2
            Integration3L3=(M3L3*Deltat3L3)+(IL2L3-M3L3)*(-1/J3L3)*((exp(-J3L3*Deltat3L3))-1);  %Integration of iL during interval 3
            Integration4L3=(M4L3*Deltat4L3)+(IL3L3-M4L3)*(-1/J4L3)*((exp(-J4L3*Deltat4L3))-1);   %Integration of iL during interval 4
            Integration5L3=(M5L3*Deltat5L3)+(IL4L3-M5L3)*(-1/J5L3)*((exp(-J5L3*Deltat5L3))-1);   %Integration of iL during interval 4
            %
            P1L3=(2*ko*Vdc1*n1/Ts)*(-Integration1L3-Integration2L3+Integration3L3+Integration4L3+Integration5L3);   %input power of high voltage port
            P2L3=(2*kin*Vdc2/Ts)*(-Integration1L3+Integration2L3+Integration3L3+Integration4L3+Integration5L3);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %done
            %All parameters used in small d region are denoted with 'S'
            %
            Vac11S=-n1*(V1+(2*VdL));
            Vac21S=0;
            Vac12S=0;
            Vac22S=0;   %Vac2 during interval 2
            Vac13S=0;
            Vac23S=(V2+(2*VdH));
            Vac14S=0;
            Vac24S=0;
            Vac15S=0;
            Vac25S=0;
            %
            J1S=(Rac+(2*nn*RonH))/Lac;
            J2S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3S=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J4S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1S=(mm*Vac11S+(nn*V2))/(Rac+(2*nn*RonH));
            M2S=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3S=((mm*n1*V1)-nn*Vac23S)/(Rac+(2*mm*n1*n1*RonL));
            M4S=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5S=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1S=Td;
            Deltat2S=(d*Ts/2)-Td;
            Deltat3S=Td;
            F1S=(Ts/2)-Td-(d*Ts/2);
            F2S=exp(-J1S*Deltat1S);
            F3S=M1S*(1-(exp(-J1S*Deltat1S)));
            F4S=exp(-J2S*Deltat2S);
            F5S=M2S*(1-(exp(-J2S*Deltat2S)));
            F6S=exp(-J3S*Deltat3S);
            F7S=M3S*(1-(exp(-J3S*Deltat3S)));
            %
            H1S=M5S*F2S*F4S*F6S*exp(-J5S*F1S);
            H2S=-M5S*F2S*F4S*F6S+F3S*F4S*F6S+F5S*F6S+F7S;
            %
            Deltat4S=(-1/J4S)*log((H1S+M4S)/(M4S-H2S));
            Deltat5S=F1S-Deltat4S;
            %
            IL0S=-M5S*(1-(exp(-J5S*Deltat5S)));
            IL1S=(IL0S*(exp(-J1S*Deltat1S)))+(M1S*(1-(exp(-J1S*Deltat1S))));
            IL2S=(IL1S*(exp(-J2S*Deltat2S)))+(M2S*(1-(exp(-J2S*Deltat2S))));
            IL3S=(IL2S*(exp(-J3S*Deltat3S)))+(M3S*(1-(exp(-J3S*Deltat3S))));
            IL4S=(IL3S*(exp(-J4S*Deltat4S)))+(M4S*(1-(exp(-J4S*Deltat4S))));
            IL5S=M5S*(1-(exp(-J5S*Deltat5S)));
            %
            Integration1S=(M1S*Deltat1S)+(IL0S-M1S)*(-1/J1S)*((exp(-J1S*Deltat1S))-1);  %Integration of iL during interval 1
            Integration2S=(M2S*Deltat2S)+(IL1S-M2S)*(-1/J2S)*((exp(-J2S*Deltat2S))-1);  %Integration of iL during interval 2
            Integration3S=(M3S*Deltat3S)+(IL2S-M3S)*(-1/J3S)*((exp(-J3S*Deltat3S))-1);  %Integration of iL during interval 3
            Integration4S=(M4S*Deltat4S)+(IL3S-M4S)*(-1/J4S)*((exp(-J4S*Deltat4S))-1);   %Integration of iL during interval 4
            Integration5S=(M5S*Deltat5S)+(IL4S-M5S)*(-1/J5S)*((exp(-J5S*Deltat5S))-1);   %Integration of iL during interval 4
            %
            P1S=(2*ko*Vdc1*n1/Ts)*(-Integration1S+Integration2S+Integration3S+Integration4S+Integration5S);   %input power of high voltage port
            P2S=(2*kin*Vdc2/Ts)*(-Integration1S-Integration2S+Integration3S+Integration4S+Integration5S);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %All parameters used in middle d region are denoted with 'M'
            %
            Vac11M=n1*(V1+(2*VdL));
            Vac21M=0;
            Vac12M=-n1*(V1+(2*VdL));
            Vac22M=0;   %Vac2 during interval 2
            Vac13M=0;
            Vac23M=0;
            Vac14M=0;
            Vac24M=(V2+(2*VdH));
            Vac15M=0;
            Vac25M=0;
            %
            J1M=(Rac+(2*nn*RonH))/Lac;
            J2M=(Rac+(2*nn*RonH))/Lac;
            J3M=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4M=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J5M=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1M=(mm*Vac11M+(nn*V2))/(Rac+(2*nn*RonH));
            M2M=(mm*Vac12M+(nn*V2))/(Rac+(2*nn*RonH));
            M3M=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4M=((mm*n1*V1)-nn*Vac24M)/(Rac+(2*mm*n1*n1*RonL));
            M5M=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat3M=(d*Ts/2)-Td;
            Deltat4M=Td;
            Deltat5M=(Ts/2)-Td-(d*Ts/2);
            F1M=exp(-J3M*Deltat3M);
            F2M=M3M*(1-(exp(-J3M*Deltat3M)));
            F3M=exp(-J4M*Deltat4M);
            F4M=M4M*(1-(exp(-J4M*Deltat4M)));
            F5M=exp(-J5M*Deltat5M);
            F6M=M5M*(1-(exp(-J5M*Deltat5M)));
            %
            H1M=M2M*F1M*F3M*F5M*exp(-J2M*Td);
            H2M=-M2M*F1M*F3M*F5M-F2M*F3M*F5M-F4M*F5M-F6M;
            %
            Deltat1M=(-1/J1M)*log((H1M+M1M)/(M1M-H2M));
            Deltat2M=Td-Deltat1M;
            %
            IL2M=(M2M*(1-(exp(-J2M*Deltat2M))));
            IL3M=(IL2M*(exp(-J3M*Deltat3M)))+(M3M*(1-(exp(-J3M*Deltat3M))));
            IL4M=(IL3M*(exp(-J4M*Deltat4M)))+(M4M*(1-(exp(-J4M*Deltat4M))));
            IL5M=(IL4M*(exp(-J5M*Deltat5M)))+M5M*(1-(exp(-J5M*Deltat5M)));
            IL0M=-IL5M;
            IL1M=(IL0M*(exp(-J1M*Deltat1M)))+(M1M*(1-(exp(-J1M*Deltat1M))));
            %
            %
            Integration1M=(M1M*Deltat1M)+(IL0M-M1M)*(-1/J1M)*((exp(-J1M*Deltat1M))-1);  %Integration of iL during interval 1
            Integration2M=(M2M*Deltat2M)+(IL1M-M2M)*(-1/J2M)*((exp(-J2M*Deltat2M))-1);  %Integration of iL during interval 2
            Integration3M=(M3M*Deltat3M)+(IL2M-M3M)*(-1/J3M)*((exp(-J3M*Deltat3M))-1);  %Integration of iL during interval 3
            Integration4M=(M4M*Deltat4M)+(IL3M-M4M)*(-1/J4M)*((exp(-J4M*Deltat4M))-1);   %Integration of iL during interval 4
            Integration5M=(M5M*Deltat5M)+(IL4M-M5M)*(-1/J5M)*((exp(-J5M*Deltat5M))-1);   %Integration of iL during interval 4
            %
            P1M=(2*ko*Vdc1*n1/Ts)*(Integration1M-Integration2M+Integration3M+Integration4M+Integration5M);   %input power of high voltage port
            P2M=(2*kin*Vdc2/Ts)*(-Integration1M-Integration2M-Integration3M+Integration4M+Integration5M);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %
            %All parameters used in large d region are denoted with 'La'
            %
            Vac11La=n1*(V1+(2*VdL));
            Vac21La=0;
            Vac12La=0;
            Vac22La=0;   %Vac2 during interval 2
            Vac13La=0;
            Vac23La=0;
            Vac14La=0;
            Vac24La=(V2+(2*VdH));
            Vac15La=0;
            Vac25La=0;
            %
            J1La=(Rac+(2*nn*RonH))/Lac;
            J2La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4La=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J5La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1La=(mm*Vac11La+(nn*V2))/(Rac+(2*nn*RonH));
            M2La=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3La=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4La=((mm*n1*V1)-nn*Vac24La)/(Rac+(2*mm*n1*n1*RonL));
            M5La=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1La=Td;
            Deltat4La=Td;
            Deltat5La=(Ts/2)-Td-(d*Ts/2);
            F1La=(d*Ts/2)-Td;
            F2La=exp(-J1La*Deltat1La);
            F3La=M1La*(1-(exp(-J1La*Deltat1La)));
            F4La=exp(-J4La*Deltat4La);
            F5La=M4La*(1-(exp(-J4La*Deltat4La)));
            F6La=exp(-J5La*Deltat5La);
            F7La=M5La*(1-(exp(-J5La*Deltat5La)));
            %
            H1La=M3La*F2La*F4La*F6La*exp(-J3La*F1La);
            H2La=-M3La*F2La*F4La*F6La-F2La*F5La*F6La-F2La*F7La+F3La;
            %
            Deltat2La=(-1/J2La)*log((H1La+M2La)/(M2La-H2La));
            Deltat3La=F1La-Deltat2La;
            %
            IL3La=(M3La*(1-(exp(-J3La*Deltat3La))));
            IL4La=(IL3La*(exp(-J4La*Deltat4La)))+(M4La*(1-(exp(-J4La*Deltat4La))));
            IL5La=(IL4La*(exp(-J5La*Deltat5La)))+M5La*(1-(exp(-J5La*Deltat5La)));
            IL0La=-IL5La;
            IL1La=(IL0La*(exp(-J1La*Deltat1La)))+(M1La*(1-(exp(-J1La*Deltat1La))));
            IL2La=(IL1La*(exp(-J2La*Deltat2La)))+(M2La*(1-(exp(-J2La*Deltat2La))));
            %
            %
            Integration1La=(M1La*Deltat1La)+(IL0La-M1La)*(-1/J1La)*((exp(-J1La*Deltat1La))-1);  %Integration of iL during interval 1
            Integration2La=(M2La*Deltat2La)+(IL1La-M2La)*(-1/J2La)*((exp(-J2La*Deltat2La))-1);  %Integration of iL during interval 2
            Integration3La=(M3La*Deltat3La)+(IL2La-M3La)*(-1/J3La)*((exp(-J3La*Deltat3La))-1);  %Integration of iL during interval 3
            Integration4La=(M4La*Deltat4La)+(IL3La-M4La)*(-1/J4La)*((exp(-J4La*Deltat4La))-1);   %Integration of iL during interval 4
            Integration5La=(M5La*Deltat5La)+(IL4La-M5La)*(-1/J5La)*((exp(-J5La*Deltat5La))-1);   %Integration of iL during interval 4
            %
            P1La=(2*ko*Vdc1*n1/Ts)*(Integration1La+Integration2La+Integration3La+Integration4La+Integration5La);   %input power of high voltage port
            P2La=(2*kin*Vdc2/Ts)*(-Integration1La-Integration2La-Integration3La+Integration4La+Integration5La);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            if d<=((2*Td)/Ts)
                a=P1L3;
                b=P2L3;
            elseif d>((2*Td)/Ts) && IL0S>=0
                a=P1S;
                b=P2S;
            elseif d>((2*Td)/Ts) && IL0M<0 && IL2M>0
                a=P1M;
                b=P2M;
            else
                a=P1La;
                b=P2La;
            end
        end
        
        function [a,b] = negapowervsdequalron(app,d,Td,fs,V1,V2,VdH,VdL,RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,Vdc1,Vdc2)
            n1=NH/NL;            %Turns ratio of transformer
            %
            % AC part parameters
            Rac=(1*n1*n1*RLe)+(1*Rtrp)+(1*n1*n1*Rtrs);  %Total resistance of AC part
            Lac=(1*n1*n1*Le)+(1*Llkp)+(1*n1*n1*Llks);  %Total inductance of AC part
            % control parameters
            Ts=1/fs;
            %
            dboundary1=Td/(Ts/2);
            %
            %All parameters used in small d 1 region are denoted with 'S'
            Vac11S=V2;
            Vac21S=V2;
            Vac12S=0;
            Vac22S=0;   %Vac2 during interval 2
            Vac13S=0;
            Vac23S=(V2+(2*VdH));
            Vac14S=0;
            Vac24S=0;
            Vac15S=-V2;
            Vac25S=-V2;
            %
            J1S=0;
            J2S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3S=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J4S=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5S=0;
            M1S=0;
            M2S=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3S=((mm*n1*V1)-nn*Vac23S)/(Rac+(2*mm*n1*n1*RonL));
            M4S=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5S=0;
            %
            Deltat1S=Td;
            Deltat2S=(d*Ts/2)-Td;
            Deltat3S=Td;
            IL2S=(M2S*(1-(exp(-J2S*Deltat2S))));
            IL3S=(IL2S*(exp(-J3S*Deltat3S)))+(M3S*(1-(exp(-J3S*Deltat3S))));
            Deltat4S=(-1/J4S)*log(M4S/(M4S-IL3S));
            Deltat5S=(Ts/2)-Td-(d*Ts/2)-Deltat4S;
            IL4S=0;
            IL5S=0;
            IL0S=-IL5S;
            IL1S=0;
            F1S=(Ts/2)-Td;
            F2S=M4S*exp(-J4S*F1S);
            F3S=(M2S-M3S)*exp(-J3S*Deltat3S)+M3S;
            F4S=M2S*exp(-J3S*Deltat3S)*exp(J2S*Deltat3S);
            F5S=F4S*exp(-J4S*F1S);
            F6S=F2S-(F3S*exp(-J4S*F1S));
            dboundarys=(1/J4S)*(2/Ts)*log((M4S-F5S)/F6S);
            %
            %Integration1S=(M1S*Deltat1S)+(IL0S-M1S)*(-1/J1S)*((exp(-J1S*Deltat1S))-1);  %Integration of iL during interval 1
            Integration2S=(M2S*Deltat2S)+(IL1S-M2S)*(-1/J2S)*((exp(-J2S*Deltat2S))-1);  %Integration of iL during interval 2
            Integration3S=(M3S*Deltat3S)+(IL2S-M3S)*(-1/J3S)*((exp(-J3S*Deltat3S))-1);  %Integration of iL during interval 3
            Integration4S=(M4S*Deltat4S)+(IL3S-M4S)*(-1/J4S)*((exp(-J4S*Deltat4S))-1);   %Integration of iL during interval 4
            %Integration5S=(M5S*Deltat5S)+(IL4S-M5S)*(-1/J5S)*((exp(-J5S*Deltat5S))-1);   %Integration of iL during interval 4
            %
            P1S=(2*ko*Vdc1*n1/Ts)*(Integration2S+Integration3S+Integration4S);   %input power of high voltage port
            P2S=(2*kin*Vdc2/Ts)*(-Integration2S+Integration3S+Integration4S);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %All parameters used in middle DCM d 1 region are denoted with 'MD'
            %
            Vac11MD=n1*(V1+(2*VdL));
            Vac21MD=0;
            Vac12MD=-V2;
            Vac22MD=-V2;   %Vac2 during interval 2
            Vac13MD=0;
            Vac23MD=0;
            Vac14MD=0;
            Vac24MD=(V2+(2*VdH));
            Vac15MD=0;
            Vac25MD=0;
            %
            J1MD=(Rac+(2*nn*RonH))/Lac;
            J2MD=0;
            J3MD=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4MD=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J5MD=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1MD=(mm*Vac11MD+(nn*V2))/(Rac+(2*nn*RonH));
            M2MD=0;
            M3MD=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4MD=((mm*n1*V1)-nn*Vac24MD)/(Rac+(2*mm*n1*n1*RonL));
            M5MD=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat3MD=(d*Ts/2)-Td;
            Deltat4MD=Td;
            Deltat5MD=(Ts/2)-Td-(d*Ts/2);
            IL2MD=0;
            IL3MD=(IL2MD*(exp(-J3MD*Deltat3MD)))+(M3MD*(1-(exp(-J3MD*Deltat3MD))));
            IL4MD=(IL3MD*(exp(-J4MD*Deltat4MD)))+(M4MD*(1-(exp(-J4MD*Deltat4MD))));
            IL5MD=(IL4MD*(exp(-J5MD*Deltat5MD)))+(M5MD*(1-(exp(-J5MD*Deltat5MD))));
            IL0MD=-IL5MD;
            IL1MD=0;
            Deltat1MD=(-1/J1MD)*log(M1MD/(M1MD-IL0MD));
            Deltat2MD=Td-Deltat1MD;
            %
            %
            Integration1MD=(M1MD*Deltat1MD)+(IL0MD-M1MD)*(-1/J1MD)*((exp(-J1MD*Deltat1MD))-1);  %Integration of iL during interval 1
            %Integration2MD=(M2MD*Deltat2MD)+(IL1MD-M2MD)*(-1/J2MD)*((exp(-J2MD*Deltat2MD))-1);  %Integration of iL during interval 2
            Integration3MD=(M3MD*Deltat3MD)+(IL2MD-M3MD)*(-1/J3MD)*((exp(-J3MD*Deltat3MD))-1);  %Integration of iL during interval 3
            Integration4MD=(M4MD*Deltat4MD)+(IL3MD-M4MD)*(-1/J4MD)*((exp(-J4MD*Deltat4MD))-1);   %Integration of iL during interval 4
            Integration5MD=(M5MD*Deltat5MD)+(IL4MD-M5MD)*(-1/J5MD)*((exp(-J5MD*Deltat5MD))-1);   %Integration of iL during interval 4
            %
            P1MD=(2*ko*Vdc1*n1/Ts)*(Integration1MD+Integration3MD+Integration4MD+Integration5MD);   %input power of high voltage port
            P2MD=(2*kin*Vdc2/Ts)*(-Integration1MD-Integration3MD+Integration4MD+Integration5MD);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %All parameters used in middle BCM d 1 region are denoted with 'MB'
            Vac11MB=n1*(V1+(2*VdL));
            Vac21MB=0;
            Vac12MB=0;
            Vac22MB=0;   %Vac2 during interval 2
            Vac13MB=0;
            Vac23MB=(V2+(2*VdH));
            Vac14MB=0;
            Vac24MB=0;
            Vac15MB=0;
            Vac25MB=0;
            %
            J1MB=(Rac+(2*nn*RonH))/Lac;
            J2MB=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3MB=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J4MB=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J5MB=0;
            M1MB=(mm*Vac11MB+(nn*V2))/(Rac+(2*nn*RonH));
            M2MB=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3MB=((mm*n1*V1)-nn*Vac23MB)/(Rac+(2*mm*n1*n1*RonL));
            M4MB=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M5MB=0;
            %
            %
            Deltat1MB=Td;
            Deltat3MB=Td;
            %
            IL0MB=(exp(-J1MB*Deltat1MB)*M1MB-M1MB)/(exp(-J1MB*Deltat1MB));
            %
            F1MB=exp(-J3MB*Deltat3MB);
            F2MB=(M3MB*(1-(exp(-J3MB*Deltat3MB))));
            F3MB=M2MB*exp(J2MB*Td);
            F4MB=(Ts/2)-Td;
            H1MB=(M2MB*F1MB+F2MB-M4MB)*exp(-J4MB*F4MB);
            H2MB=(-F1MB*F3MB*exp(-J4MB*F4MB))+M4MB+IL0MB;
            %
            Deltat2MB=(d*Ts/2)-Td;
            Deltat4MB=(Ts/2)-Td-(d*Ts/2);
            Deltat5MB=0;
            IL1MB=(IL0MB*(exp(-J1MB*Deltat1MB)))+(M1MB*(1-(exp(-J1MB*Deltat1MB))));
            IL2MB=(IL1MB*(exp(-J2MB*Deltat2MB)))+(M2MB*(1-(exp(-J2MB*Deltat2MB))));
            IL3MB=(IL2MB*(exp(-J3MB*Deltat3MB)))+(M3MB*(1-(exp(-J3MB*Deltat3MB))));
            IL4MB=(IL3MB*(exp(-J4MB*Deltat4MB)))+(M4MB*(1-(exp(-J4MB*Deltat4MB))));
            IL5MB=(IL4MB*(exp(-J5MB*Deltat5MB)))+(M5MB*(1-(exp(-J5MB*Deltat5MB))));
            %
            Integration1MB=(M1MB*Deltat1MB)+(IL0MB-M1MB)*(-1/J1MB)*((exp(-J1MB*Deltat1MB))-1);  %Integration of iL during interval 1
            Integration2MB=(M2MB*Deltat2MB)+(IL1MB-M2MB)*(-1/J2MB)*((exp(-J2MB*Deltat2MB))-1);  %Integration of iL during interval 2
            Integration3MB=(M3MB*Deltat3MB)+(IL2MB-M3MB)*(-1/J3MB)*((exp(-J3MB*Deltat3MB))-1);  %Integration of iL during interval 3
            Integration4MB=(M4MB*Deltat4MB)+(IL3MB-M4MB)*(-1/J4MB)*((exp(-J4MB*Deltat4MB))-1);   %Integration of iL during interval 4
            Integration5MB=(M5MB*Deltat5MB)+(IL4MB-M5MB)*(-1/J5MB)*((exp(-J5MB*Deltat5MB))-1);   %Integration of iL during interval 4
            %
            P1MB=(2*ko*Vdc1*n1/Ts)*(Integration1MB+Integration2MB+Integration3MB+Integration4MB);   %input power of high voltage port
            P2MB=(2*kin*Vdc2/Ts)*(-Integration1MB-Integration2MB+Integration3MB+Integration4MB);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            %All parameters used in large CCM d 1 region are denoted with 'La'
            %
            Vac11La=n1*(V1+(2*VdL));
            Vac21La=0;
            Vac12La=0;
            Vac22La=0;   %Vac2 during interval 2
            Vac13La=0;
            Vac23La=0;
            Vac14La=0;
            Vac24La=(V2+(2*VdH));
            Vac15La=0;
            Vac25La=0;
            %
            J1La=(Rac+(2*nn*RonH))/Lac;
            J2La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J3La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            J4La=(Rac+(2*mm*n1*n1*RonL))/Lac;
            J5La=(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL))/Lac;
            M1La=(mm*Vac11La+(nn*V2))/(Rac+(2*nn*RonH));
            M2La=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M3La=((mm*n1*V1)+(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            M4La=((mm*n1*V1)-nn*Vac24La)/(Rac+(2*mm*n1*n1*RonL));
            M5La=((mm*n1*V1)-(nn*V2))/(Rac+(2*nn*RonH)+(2*mm*n1*n1*RonL));
            %
            Deltat1La=Td;
            Deltat4La=Td;
            Deltat5La=(Ts/2)-Td-(d*Ts/2);
            F1La=(d*Ts/2)-Td;
            F2La=exp(-J1La*Deltat1La);
            F3La=M1La*(1-(exp(-J1La*Deltat1La)));
            F4La=exp(-J4La*Deltat4La);
            F5La=M4La*(1-(exp(-J4La*Deltat4La)));
            F6La=exp(-J5La*Deltat5La);
            F7La=M5La*(1-(exp(-J5La*Deltat5La)));
            %
            H1La=M3La*F2La*F4La*F6La*exp(-J3La*F1La);
            H2La=-M3La*F2La*F4La*F6La-F2La*F5La*F6La-F2La*F7La+F3La;
            %
            Deltat2La=(-1/J2La)*log((H1La+M2La)/(M2La-H2La));
            Deltat3La=F1La-Deltat2La;
            %
            IL3La=(M3La*(1-(exp(-J3La*Deltat3La))));
            IL4La=(IL3La*(exp(-J4La*Deltat4La)))+(M4La*(1-(exp(-J4La*Deltat4La))));
            IL5La=(IL4La*(exp(-J5La*Deltat5La)))+M5La*(1-(exp(-J5La*Deltat5La)));
            IL0La=-IL5La;
            IL1La=(IL0La*(exp(-J1La*Deltat1La)))+(M1La*(1-(exp(-J1La*Deltat1La))));
            IL2La=(IL1La*(exp(-J2La*Deltat2La)))+(M2La*(1-(exp(-J2La*Deltat2La))));
            %
            Integration1La=(M1La*Deltat1La)+(IL0La-M1La)*(-1/J1La)*((exp(-J1La*Deltat1La))-1);  %Integration of iL during interval 1
            Integration2La=(M2La*Deltat2La)+(IL1La-M2La)*(-1/J2La)*((exp(-J2La*Deltat2La))-1);  %Integration of iL during interval 2
            Integration3La=(M3La*Deltat3La)+(IL2La-M3La)*(-1/J3La)*((exp(-J3La*Deltat3La))-1);  %Integration of iL during interval 3
            Integration4La=(M4La*Deltat4La)+(IL3La-M4La)*(-1/J4La)*((exp(-J4La*Deltat4La))-1);   %Integration of iL during interval 4
            Integration5La=(M5La*Deltat5La)+(IL4La-M5La)*(-1/J5La)*((exp(-J5La*Deltat5La))-1);   %Integration of iL during interval 4
            %
            P1La=(2*ko*Vdc1*n1/Ts)*(Integration1La+Integration2La+Integration3La+Integration4La+Integration5La);   %input power of high voltage port
            P2La=(2*kin*Vdc2/Ts)*(-Integration1La-Integration2La-Integration3La+Integration4La+Integration5La);   %output power of low voltage port
            %Pc=(n1*V1*V2*d*(1-d))/(2*fs*(2*n1*n1*Le));  %Transfered power calculated by lossless model, both prots share the same value
            %
            dboundary=(2/Ts)*(1/J4MB)*log(-H2MB/H1MB);
            %
            if d<dboundary1
                a=0;
                b=0;
            elseif d>=dboundary1 && d<dboundarys
                a=P1S;
                b=P2S;
            elseif d>=dboundarys && d<dboundary
                a=P1MD;
                b=P2MD;
            elseif d==dboundary
                a=P1MB;
                b=P2MB;
            else
                a=P1La;
                b=P2La;
            end
        end
        
        function [nn,mag1,mm,mag2,kin,ko,krin,kro] = selectmodule(app,module,Nm)
            if contains(module,'ISOP - 1')
                nn = 1;
                mag1 = 1/Nm;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = Nm;
                krin = 1;
                kro = 1;
            elseif contains(module,'ISOP - 2')
                nn = 1;
                mag1 = 1/Nm;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = Nm;
                krin = 1;
                kro = Nm;
            elseif contains(module,'ISOP - 3')
                nn = 1/Nm;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = Nm;
                krin = 1;
                kro = 1;
            elseif contains(module,'ISOP - 4')
                nn = 1;
                mag1 = 1/Nm;
                mm = 1;
                mag2 = 1;
                kin = 1/Nm;
                ko = 1;
                krin = 1/Nm;
                kro = 1;
            elseif contains(module,'ISOP - 5')
                nn = 1/Nm;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = Nm;
                krin = 1;
                kro = Nm;
            elseif contains(module,'ISOP - 6')
                nn = 1;
                mag1 = 1;
                mm = Nm;
                mag2 = 1;
                kin = 1;
                ko = Nm;
                krin = 1;
                kro = 1;
            elseif contains(module,'IPOS - 1')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1/Nm;
                kin = Nm;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'IPOS - 2')
                nn = 1;
                mag1 = 1;
                mm = 1/Nm;
                mag2 = 1;
                kin = Nm;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'IPOS - 3')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1/Nm;
                kin = Nm;
                ko = 1;
                krin = Nm;
                kro = 1;
            elseif contains(module,'IPOS - 4')
                nn = Nm;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = Nm;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'IPOS - 5')
                nn = 1;
                mag1 = 1;
                mm = 1/Nm;
                mag2 = 1;
                kin = Nm;
                ko = 1;
                krin = Nm;
                kro = 1;
            elseif contains(module,'IPOS - 6')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1/Nm;
                kin = 1;
                ko = 1/Nm;
                krin = 1;
                kro = 1/Nm;
            elseif contains(module,'SS - 1')
                nn = 1;
                mag1 = 1/Nm;
                mm = 1;
                mag2 = 1/Nm;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'SS - 2')
                nn = 1;
                mag1 = 1/Nm;
                mm = 1/Nm;
                mag2 = 1;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'SS - 3')
                nn = 1/Nm;
                mag1 = 1;
                mm = 1;
                mag2 = 1/Nm;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'SS - 4')
                nn = Nm;
                mag1 = 1/Nm;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'SS - 5')
                nn = 1/Nm;
                mag1 = 1;
                mm = 1/Nm;
                mag2 = 1;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'SS - 6')
                nn = 1;
                mag1 = 1;
                mm = Nm;
                mag2 = 1/Nm;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1;
            elseif contains(module,'PP - 1')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = Nm;
                ko = Nm;
                krin = 1;
                kro = 1;
            elseif contains(module,'PP - 2')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = Nm;
                ko = Nm;
                krin = 1;
                kro = Nm;
            elseif contains(module,'PP - 3')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = Nm;
                ko = Nm;
                krin = Nm;
                kro = 1;
            elseif contains(module,'PP - 4')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = 1;
                krin = 1/Nm;
                kro = 1;
            elseif contains(module,'PP - 5')
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = Nm;
                ko = Nm;
                krin = Nm;
                kro = Nm;
            else
                nn = 1;
                mag1 = 1;
                mm = 1;
                mag2 = 1;
                kin = 1;
                ko = 1;
                krin = 1;
                kro = 1/Nm;
            end
        end
        
        function [I_0,I_t,RT_lead,RT_lag_after,RT_lag_during]=vertical_comp(app,fs,Ts,nn,V_H,mm,V_L,n,L_H,d,C_oss_H,C_oss_L,krin,kro)
                %
                if d > 0
                    I_0 = -(1/(4*fs*L_H))*(d*2*n*V_L+V_H-n*V_L);
                    I_t = I_0 + (1/L_H)*(V_H + n*V_L)*d*(Ts/2);
                    %
                    %RT_lead = abs(C_oss_H*(V_H/nn)/(krin*I_0/2));
                    N_res = 100;
                    V_res = zeros(N_res,1);
                    I_res = zeros(N_res,1);
                    Resolution_res = 1e-9;
                    delta_V = 0;
                    count_reso = 1;
                    i_ini = krin*I_0;
                    while(delta_V <= V_H/nn)
                        V_res(count_reso,1) = -V_H/nn + 2*delta_V;
                        I_res(count_reso,1) = i_ini;
                        count_reso = count_reso + 1;
                        i_ini = i_ini + krin*(1/L_H)*(-V_H + 2*delta_V*nn + n*V_L)*Resolution_res;
                        delta_V = delta_V + abs((i_ini/2)*Resolution_res/C_oss_H);
                    end
                    RT_lead = Resolution_res*(count_reso - 1);
                    %RT_L_after = abs(C_oss_L*V_L/(n1*I_t/2))
                    N_res = 100;
                    V_res = zeros(N_res,1);
                    I_res = zeros(N_res,1);
                    Resolution_res = 1e-9;
                    delta_V = 0;
                    count_reso = 1;
                    i_ini = kro*n*I_t;
                    while(delta_V <= V_L/mm)
                        V_res(count_reso,1) = -V_L/mm + 2*delta_V;
                        I_res(count_reso,1) = i_ini;
                        count_reso = count_reso + 1;
                        i_ini = i_ini + kro*n*(1/L_H)*(V_H + n*(V_L - 2*delta_V*mm))*Resolution_res;
                        delta_V = delta_V + abs((i_ini/2)*Resolution_res/C_oss_L);
                    end
                    RT_lag_after = Resolution_res*(count_reso - 1);
                    %
                    N_res = 100;
                    V_res = zeros(N_res,1);
                    I_res = zeros(N_res,1);
                    Resolution_res = 1e-9;
                    delta_V = 0;
                    count_reso = 1;
                    i_ini = 0;
                    while(delta_V <= V_L/mm)
                        V_res(count_reso,1) = -V_L/mm + 2*delta_V;
                        I_res(count_reso,1) = i_ini;
                        count_reso = count_reso + 1;
                        i_ini = i_ini + kro*n*(1/L_H)*(V_H + n*(V_L - 2*delta_V*mm))*Resolution_res;
                        delta_V = delta_V + abs((i_ini/2)*Resolution_res/C_oss_L);
                    end
                    RT_lag_during = Resolution_res*(count_reso - 1);
                else
                    I_0 = -(1/(4*fs*L_H))*(-d*2*n*V_L+V_H+n*V_L);
                    I_t = I_0 + (1/L_H)*(V_H + n*V_L)*(-d)*(Ts/2);
                    %
                    %RT_H = abs(C_oss_H*(V_H/nn)/(krin*I_0/2));
                    N_res = 100;
                    V_res = zeros(N_res,1);
                    I_res = zeros(N_res,1);
                    Resolution_res = 1e-9;
                    delta_V = 0;
                    count_reso = 1;
                    i_ini = krin*I_0;
                    while(delta_V <= V_H/nn)
                        V_res(count_reso,1) = -V_H/nn + 2*delta_V;
                        I_res(count_reso,1) = i_ini;
                        count_reso = count_reso + 1;
                        i_ini = i_ini + krin*(1/L_H)*(-V_H + 2*delta_V*nn - n*V_L)*Resolution_res;
                        delta_V = delta_V + abs((i_ini/2)*Resolution_res/C_oss_H);
                    end
                    RT_lag_after = Resolution_res*(count_reso - 1);
                    %RT_L_after = abs(C_oss_L*V_L/(n1*I_t/2))
                    N_res = 100;
                    V_res = zeros(N_res,1);
                    I_res = zeros(N_res,1);
                    Resolution_res = 1e-9;
                    delta_V = 0;
                    count_reso = 1;
                    i_ini = kro*n*I_t;
                    while(delta_V <= V_L/mm)
                        V_res(count_reso,1) = -V_L/mm + 2*delta_V;
                        I_res(count_reso,1) = i_ini;
                        count_reso = count_reso + 1;
                        i_ini = i_ini + kro*n*(1/L_H)*(-V_H + n*(V_L - 2*delta_V*mm))*Resolution_res;
                        delta_V = delta_V + abs((i_ini/2)*Resolution_res/C_oss_L);
                    end
                    RT_lead = Resolution_res*(count_reso - 1);
                    %
                    N_res = 100;
                    V_res = zeros(N_res,1);
                    I_res = zeros(N_res,1);
                    Resolution_res = 1e-9;
                    delta_V = 0;
                    count_reso = 1;
                    i_ini = 0;
                    while(delta_V <= V_H/nn)
                        V_res(count_reso,1) = -V_H/nn + 2*delta_V;
                        I_res(count_reso,1) = i_ini;
                        count_reso = count_reso + 1;
                        i_ini = i_ini + krin*(1/L_H)*(-V_H + 2*delta_V*nn - n*V_L)*Resolution_res;
                        delta_V = delta_V + abs((i_ini/2)*Resolution_res/C_oss_H);
                    end
                    RT_lag_during = Resolution_res*(count_reso - 1);
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            VdH = app.VdHEditField.Value;
            VdL = app.VdLEditField.Value;
            RonH = app.RonHEditField.Value;
            RonL = app.RonLEditField.Value;
            RLe = app.RLeEditField.Value;
            Le = app.LeEditField.Value;
            NH = app.NHEditField.Value;
            NL = app.NLEditField.Value;
            n1 = NH/NL;
            %
            Rtrp = app.RtrpEditField.Value;
            Rtrs = app.RtrsEditField.Value;
            Llkp = app.LlkpEditField.Value;
            Llks = app.LlksEditField.Value;
            % voltages of two ports
            V1 = app.VHEditField.Value;
            V2 = app.VLEditField.Value;
            L_H = n1*n1*(Le + Llks) + Llkp;
            %
            fs = app.fsEditField.Value;
            Ts=1/fs;
            Td = app.TdEditField.Value;
            %
            C_oss_H = app.CossHEditField.Value;
            C_oss_L = app.CossLEditField.Value;
            %
            d_want = app.dEditField.Value;
            %
            Nm = app.NmEditField.Value;
            module = app.ModulestructureDropDown.Value;
            %
            [nn,mag1,mm,mag2,kin,ko,krin,kro] = app.selectmodule(module,Nm);
            if d_want>0
                if (nn*mag1*V1)>(n1*mm*mag2*V2)
                    [a,b] = app.posipowervsdmoreron(d_want,Td,fs,mag1*V1,mag2*V2,VdH,VdL,...
                        RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                    %
                    d_start = 0.01;
                    d_stop = 0.5;
                    d_step = 0.002;
                    d_sweep = d_start:d_step:d_stop;
                    arrPo = zeros(1,size(d_sweep,2));
                    arrPin = zeros(1,size(d_sweep,2));
                    arrITA = zeros(1,size(d_sweep,2));
                    count = 0;
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.posipowervsdmoreron(d,Td,fs,mag1*V1,mag2*V2,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                        arrPo(1,count) = b;
                        arrPin(1,count) = a;
                        arrITA(1,count) = round(b / a, 3);
                    end
                    % calculate derivative and rising time
                    arrDeri_Po = zeros(1,size(d_sweep,2));
                    arrDeri_Pin = zeros(1,size(d_sweep,2));
                    arrPo_comp = arrPo;
                    arrPin_comp = arrPin;
                    for i = 1:1:size(arrPo,2)-1
                        arrDeri_Po(1,i+1) = arrPo(1,i+1)-arrPo(1,i);
                        arrDeri_Pin(1,i+1) = arrPin(1,i+1)-arrPin(1,i);
                    end
                    [row,col] = find(arrDeri_Po<0,1);
                    start_index = col-1;
                    end_index = start_index + ceil((Td/(Ts/2))/d_step);
                    [I_0,I_t,RT_lead,RT_lag_after,RT_lag_during] = app.vertical_comp(fs,Ts,nn,nn*mag1*V1,mm,mm*mag2*V2,NH/NL,L_H,d_sweep(1,start_index),C_oss_H,C_oss_L,krin,kro);
                    %app.RTleadEditField.Value = RT_lead;
                    %app.RTlagEditField.Value = RT_lag_during;
                    % calculate horizontal shift
                    I_0 = abs(I_0);
                    deri2 = -V1*kin*mm*mag2*V2/(fs*n1*Le);
                    % delay due to primary side
                    %
                    delta_t_p_ideal = L_H*I_0/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_I_p_real_dead = RT_lead*mm*mag2*V2/(n1*Le);
                    delta_t_p_real_post_dead = L_H*(I_0-delta_I_p_real_dead)/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_t_p_real = RT_lead+delta_t_p_real_post_dead;
                    delay_t_p = delta_t_p_real-delta_t_p_ideal;
                    % delay due to secondary side
                    d_delay = (delay_t_p+RT_lag_during)/(Ts/2);
                    if RT_lag_during<Td
                        d_duc = (Td-RT_lag_during)/(Ts/2);
                        d_dec = (RT_lag_during)/(Ts/2);
                    else
                        d_duc = 0;
                        d_dec = (Td)/(Ts/2);
                    end
                    delay_index = ceil(d_delay/d_step);
                    deduc_index = ceil(d_duc/d_step);
                    dec_index = ceil(d_dec/d_step);
                    
                    % implement horizontal compensation
                    for i = start_index:1:start_index+delay_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrPo_comp(1,i-1)-arrPo_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrPin_comp(1,i-1)-arrPin_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                    end
                    for i = start_index+delay_index+1:1:start_index+delay_index+1+deduc_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index);
                    end
                    for i = start_index+delay_index+1+deduc_index+1:1:size(arrPo,2)
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index+dec_index-1);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index+dec_index-1);
                    end
                    
                    arrPin = arrPin_comp;
                    arrPo = arrPo_comp;
                    index_want = (d_want - d_start) / d_step + 1;
                    a = arrPin_comp(1,index_want);
                    b = arrPo_comp(1,index_want);
                    
                elseif (nn*mag1*V1)<(n1*mm*mag2*V2)
                    [a,b] = app.posipowervsdlessron(d_want,Td,fs,mag1*V1,mag2*V2,VdH,VdL,...
                        RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                else
                    [a,b] = app.posipowervsdequalron(d_want,Td,fs,mag1*V1*1.01,mag2*V2,VdH,VdL,...
                        RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                end
                app.PHVEditField.Value = a;
                app.PLVEditField.Value = b;
                app.lossEditField.Value = a - b;
                app.etaEditField.Value = round(b / a, 3);
            else
                if (n1*mm*mag2*V2)>(nn*mag1*V1)
                    [a,b] = app.negapowervsdmoreron(-d_want,Td,fs,mag2*V2,mag1*V1,VdH,VdL,...
                        RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                    %
                    d_start = -0.01;
                    d_stop = -0.5;
                    d_step = -0.002;
                    d_sweep = d_start:d_step:d_stop;
                    arrPo = zeros(1,size(d_sweep,2));
                    arrPin = zeros(1,size(d_sweep,2));
                    arrITA = zeros(1,size(d_sweep,2));
                    count = 0;
                    %
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.negapowervsdmoreron(-d,Td,fs,mag2*V2,mag1*V1,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                        arrPo(1,count) = a;
                        arrPin(1,count) = b;
                        arrITA(1,count) = round(b / a, 3);
                    end
                    % calculate derivative and rising time
                    arrDeri_Po = zeros(1,size(d_sweep,2));
                    arrDeri_Pin = zeros(1,size(d_sweep,2));
                    arrPo_comp = arrPo;
                    arrPin_comp = arrPin;
                    for i = 1:1:size(arrPo,2)-1
                        arrDeri_Po(1,i+1) = arrPo(1,i+1)-arrPo(1,i);
                        arrDeri_Pin(1,i+1) = arrPin(1,i+1)-arrPin(1,i);
                    end
                    [row,col] = find(arrDeri_Po<0,1);
                    start_index = col-1;
                    end_index = start_index + ceil((Td/(Ts/2))/abs(d_step));
                    [I_0,I_t,RT_lead,RT_lag_after,RT_lag_during] = app.vertical_comp(fs,Ts,nn,nn*mag1*V1,mm,mm*mag2*V2,NH/NL,L_H,d_sweep(1,start_index),C_oss_H,C_oss_L,krin,kro);
                    %app.RTleadEditField.Value = RT_lead;
                    %app.RTlagEditField.Value = RT_lag_during;
                    % calculate horizontal shift
                    I_0 = abs(I_t);
                    deri2 = -V1*kin*mm*mag2*V2/(fs*n1*Le);
                    % delay due to primary side
                    %
                    delta_t_p_ideal = L_H*I_0/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_I_p_real_dead = RT_lead*nn*mag1*V1/(n1*Le);
                    delta_t_p_real_post_dead = L_H*(I_0-delta_I_p_real_dead)/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_t_p_real = RT_lead+delta_t_p_real_post_dead;
                    delay_t_p = delta_t_p_real-delta_t_p_ideal;
                    % delay due to secondary side
                    d_delay = (delay_t_p+RT_lag_during)/(Ts/2);
                    if RT_lag_during<Td
                        d_duc = (Td-RT_lag_during)/(Ts/2);
                        d_dec = (RT_lag_during)/(Ts/2);
                    else
                        d_duc = 0;
                        d_dec = (Td)/(Ts/2);
                    end
                    delay_index = ceil(d_delay/abs(d_step));
                    deduc_index = ceil(d_duc/abs(d_step));
                    dec_index = ceil(d_dec/abs(d_step));
                    %app.indexEditField.Value = start_index;
                    % implement horizontal compensation
                    for i = start_index:1:start_index+delay_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrPo_comp(1,i-1)-arrPo_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrPin_comp(1,i-1)-arrPin_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                    end
                    for i = start_index+delay_index+1:1:start_index+delay_index+1+deduc_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index);
                    end
                    for i = start_index+delay_index+1+deduc_index+1:1:size(arrPo,2)
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index+dec_index-1);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index+dec_index-1);
                    end
                    
                    index_want = (d_want - d_start) / d_step + 1;
                    b = arrPin_comp(1,index_want);
                    a = arrPo_comp(1,index_want);
                    
                elseif (n1*mm*mag2*V2)<(nn*mag1*V1)
                    [a,b] = app.negapowervsdlessron(-d_want,Td,fs,mag2*V2,mag1*V1,VdH,VdL,...
                        RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                else
                    [a,b] = app.negapowervsdequalron(-d_want,Td,fs,mag2*V2*1.01,mag1*V1,VdH,VdL,...
                        RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                end
                app.PHVEditField.Value = -b;
                app.PLVEditField.Value = -a;
                app.lossEditField.Value = a - b;
                app.etaEditField.Value = round(b / a, 3);
            end
            
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            VdH = app.VdHEditField.Value;
            VdL = app.VdLEditField.Value;
            RonH = app.RonHEditField.Value;
            RonL = app.RonLEditField.Value;
            RLe = app.RLeEditField.Value;
            Le = app.LeEditField.Value;
            NH = app.NHEditField.Value;
            NL = app.NLEditField.Value;
            n1 = NH/NL;
            Rtrp = app.RtrpEditField.Value;
            Rtrs = app.RtrsEditField.Value;
            Llkp = app.LlkpEditField.Value;
            Llks = app.LlksEditField.Value;
            L_H = n1*n1*(Le + Llks) + Llkp;
            %
            % voltages of two ports
            V1 = app.VHEditField.Value;
            V2 = app.VLEditField.Value;
            %
            fs = app.fsEditField.Value;
            Ts=1/fs;
            Td = app.TdEditField.Value;
            %
            C_oss_H = app.CossHEditField.Value;
            C_oss_L = app.CossLEditField.Value;
            %
            d_start = app.dbEditField.Value;
            d_stop = app.dtEditField.Value;
            d_step = app.dstepEditField.Value;
            d_sweep = d_start:d_step:d_stop;
            
            arrPo = zeros(1,size(d_sweep,2));
            arrPin = zeros(1,size(d_sweep,2));
            arrITA = zeros(1,size(d_sweep,2));
            count = 0;
            %
            Nm = app.NmEditField.Value;
            module = app.ModulestructureDropDown.Value;
            %
            [nn,mag1,mm,mag2,kin,ko,krin,kro] = app.selectmodule(module,Nm);
            if d_start>0
                if (nn*mag1*V1)>(n1*mm*mag2*V2)
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.posipowervsdmoreron(d,Td,fs,mag1*V1,mag2*V2,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                        arrPo(1,count) = b;
                        arrPin(1,count) = a;
                        arrITA(1,count) = round(b / a, 3);
                    end
                    % calculate derivative and rising time
                    arrDeri_Po = zeros(1,size(d_sweep,2));
                    arrDeri_Pin = zeros(1,size(d_sweep,2));
                    arrPo_comp = arrPo;
                    arrPin_comp = arrPin;
                    for i = 1:1:size(arrPo,2)-1
                        arrDeri_Po(1,i+1) = arrPo(1,i+1)-arrPo(1,i);
                        arrDeri_Pin(1,i+1) = arrPin(1,i+1)-arrPin(1,i);
                    end
                    [row,col] = find(arrDeri_Po<0,1);
                    start_index = col-1;
                    end_index = start_index + ceil((Td/(Ts/2))/d_step);
                    if start_index <= 2
                        d_start = 0.01;
                        d_sweep = d_start:d_step:d_stop;
                        arrPo = zeros(1,size(d_sweep,2));
                        arrPin = zeros(1,size(d_sweep,2));
                        arrITA = zeros(1,size(d_sweep,2));
                        count = 0;
                        for d = d_sweep
                            count = count + 1;
                            [a,b] = app.posipowervsdmoreron(d,Td,fs,mag1*V1,mag2*V2,VdH,VdL,...
                                RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                            arrPo(1,count) = b;
                            arrPin(1,count) = a;
                            arrITA(1,count) = round(b / a, 3);
                        end
                        % calculate derivative and rising time
                        arrDeri_Po = zeros(1,size(d_sweep,2));
                        arrDeri_Pin = zeros(1,size(d_sweep,2));
                        arrPo_comp = arrPo;
                        arrPin_comp = arrPin;
                        for i = 1:1:size(arrPo,2)-1
                            arrDeri_Po(1,i+1) = arrPo(1,i+1)-arrPo(1,i);
                            arrDeri_Pin(1,i+1) = arrPin(1,i+1)-arrPin(1,i);
                        end
                        [row,col] = find(arrDeri_Po<0,1);
                        start_index = col-1;
                        end_index = start_index + ceil((Td/(Ts/2))/d_step);
                    end
                    [I_0,I_t,RT_lead,RT_lag_after,RT_lag_during] = app.vertical_comp(fs,Ts,nn,nn*mag1*V1,mm,mm*mag2*V2,NH/NL,L_H,d_sweep(1,start_index),C_oss_H,C_oss_L,krin,kro);
                    %app.RTleadEditField.Value = RT_lead;
                    %app.RTlagEditField.Value = RT_lag_during;
                    % calculate horizontal shift
                    I_0 = abs(I_0);
                    deri2 = -V1*kin*mm*mag2*V2/(fs*n1*Le);
                    % delay due to primary side
                    %
                    delta_t_p_ideal = L_H*I_0/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_I_p_real_dead = RT_lead*mm*mag2*V2/(n1*Le);
                    delta_t_p_real_post_dead = L_H*(I_0-delta_I_p_real_dead)/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_t_p_real = RT_lead+delta_t_p_real_post_dead;
                    delay_t_p = delta_t_p_real-delta_t_p_ideal;
                    % delay due to secondary side
                    d_delay = (delay_t_p+RT_lag_during)/(Ts/2);
                    if RT_lag_during<Td
                        d_duc = (Td-RT_lag_during)/(Ts/2);
                        d_dec = (RT_lag_during)/(Ts/2);
                    else
                        d_duc = 0;
                        d_dec = (Td)/(Ts/2);
                    end
                    delay_index = ceil(d_delay/d_step);
                    deduc_index = ceil(d_duc/d_step);
                    dec_index = ceil(d_dec/d_step);
                    
                    % implement horizontal compensation
                    for i = start_index:1:start_index+delay_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrPo_comp(1,i-1)-arrPo_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrPin_comp(1,i-1)-arrPin_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                    end
                    for i = start_index+delay_index+1:1:start_index+delay_index+1+deduc_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index);
                    end
                    for i = start_index+delay_index+1+deduc_index+1:1:size(arrPo,2)
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index+dec_index-1);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index+dec_index-1);
                    end
                    
                    arrPin = arrPin_comp;
                    arrPo = arrPo_comp;
                    
                elseif (nn*mag1*V1)<(n1*mm*mag2*V2)
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.posipowervsdlessron(d,Td,fs,mag1*V1,mag2*V2,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                        arrPo(1,count) = b;
                        arrPin(1,count) = a;
                        arrITA(1,count) = round(b / a, 3);
                    end
                else
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.posipowervsdequalron(d,Td,fs,mag1*V1*1.01,mag2*V2,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V1,V2);
                        arrPo(1,count) = b;
                        arrPin(1,count) = a;
                        arrITA(1,count) = round(b / a, 3);
                    end
                end
                plot(app.UIAxes,d_sweep,arrPin,d_sweep,arrPo);
                legend(app.UIAxes,{'Power HV','Power LV'},'Location','Best')
                xlabel(app.UIAxes,'unified phase shift');
                ylabel(app.UIAxes,'Power [W]');
                title(app.UIAxes,'Power vs. unified phase shift');
            else
                if (n1*mm*mag2*V2)>(nn*mag1*V1)
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.negapowervsdmoreron(-d,Td,fs,mag2*V2,mag1*V1,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                        arrPo(1,count) = a;
                        arrPin(1,count) = b;
                        arrITA(1,count) = round(b / a, 3);
                    end
                    % calculate derivative and rising time
                    arrDeri_Po = zeros(1,size(d_sweep,2));
                    arrDeri_Pin = zeros(1,size(d_sweep,2));
                    arrPo_comp = arrPo;
                    arrPin_comp = arrPin;
                    for i = 1:1:size(arrPo,2)-1
                        arrDeri_Po(1,i+1) = arrPo(1,i+1)-arrPo(1,i);
                        arrDeri_Pin(1,i+1) = arrPin(1,i+1)-arrPin(1,i);
                    end
                    [row,col] = find(arrDeri_Po<0,1);
                    start_index = col-1;
                    end_index = start_index + ceil((Td/(Ts/2))/abs(d_step));
                    if start_index <= 2
                        d_start = -0.01;
                        d_sweep = d_start:d_step:d_stop;
                        arrPo = zeros(1,size(d_sweep,2));
                        arrPin = zeros(1,size(d_sweep,2));
                        arrITA = zeros(1,size(d_sweep,2));
                        count = 0;
                        for d = d_sweep
                            count = count + 1;
                            [a,b] = app.negapowervsdmoreron(-d,Td,fs,mag2*V2,mag1*V1,VdH,VdL,...
                                RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                            arrPo(1,count) = a;
                            arrPin(1,count) = b;
                            arrITA(1,count) = round(b / a, 3);
                        end
                        % calculate derivative and rising time
                        arrDeri_Po = zeros(1,size(d_sweep,2));
                        arrDeri_Pin = zeros(1,size(d_sweep,2));
                        arrPo_comp = arrPo;
                        arrPin_comp = arrPin;
                        for i = 1:1:size(arrPo,2)-1
                            arrDeri_Po(1,i+1) = arrPo(1,i+1)-arrPo(1,i);
                            arrDeri_Pin(1,i+1) = arrPin(1,i+1)-arrPin(1,i);
                        end
                        [row,col] = find(arrDeri_Po<0,1);
                        start_index = col-1;
                        end_index = start_index + ceil((Td/(Ts/2))/abs(d_step));
                    end
                    [I_0,I_t,RT_lead,RT_lag_after,RT_lag_during] = app.vertical_comp(fs,Ts,nn,nn*mag1*V1,mm,mm*mag2*V2,NH/NL,L_H,d_sweep(1,start_index),C_oss_H,C_oss_L,krin,kro);
                    %app.RTleadEditField.Value = RT_lead;
                    %app.RTlagEditField.Value = RT_lag_during;
                    % calculate horizontal shift
                    I_0 = abs(I_t);
                    deri2 = -V1*kin*mm*mag2*V2/(fs*n1*Le);
                    % delay due to primary side
                    %
                    delta_t_p_ideal = L_H*I_0/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_I_p_real_dead = RT_lead*nn*mag1*V1/(n1*Le);
                    delta_t_p_real_post_dead = L_H*(I_0-delta_I_p_real_dead)/(nn*mag1*V1+n1*mm*mag2*V2);
                    delta_t_p_real = RT_lead+delta_t_p_real_post_dead;
                    delay_t_p = delta_t_p_real-delta_t_p_ideal;
                    % delay due to secondary side
                    d_delay = (delay_t_p+RT_lag_during)/(Ts/2);
                    if RT_lag_during<Td
                        d_duc = (Td-RT_lag_during)/(Ts/2);
                        d_dec = (RT_lag_during)/(Ts/2);
                    else
                        d_duc = 0;
                        d_dec = (Td)/(Ts/2);
                    end
                    delay_index = ceil(d_delay/abs(d_step));
                    deduc_index = ceil(d_duc/abs(d_step));
                    dec_index = ceil(d_dec/abs(d_step));
                    %app.indexEditField.Value = start_index;
                    % implement horizontal compensation
                    for i = start_index:1:start_index+delay_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrPo_comp(1,i-1)-arrPo_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrPin_comp(1,i-1)-arrPin_comp(1,i-2)+abs(d_step)*abs(d_step)*deri2;
                    end
                    for i = start_index+delay_index+1:1:start_index+delay_index+1+deduc_index
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index);
                    end
                    for i = start_index+delay_index+1+deduc_index+1:1:size(arrPo,2)
                        arrPo_comp(1,i) = arrPo_comp(1,i-1)+arrDeri_Po(1,i-delay_index+dec_index-1);
                        arrPin_comp(1,i) = arrPin_comp(1,i-1)+arrDeri_Pin(1,i-delay_index+dec_index-1);
                    end
                    
                    arrPin = -arrPin_comp;
                    arrPo = -arrPo_comp;
                    
                elseif (n1*mm*mag2*V2)<(nn*mag1*V1)
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.negapowervsdlessron(-d,Td,fs,mag2*V2,mag1*V1,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                        arrPo(1,count) = -a;
                        arrPin(1,count) = -b;
                        arrITA(1,count) = round(b / a, 3);
                    end
                else
                    for d = d_sweep
                        count = count + 1;
                        [a,b] = app.negapowervsdequalron(-d,Td,fs,mag2*V2*1.01,mag1*V1,VdH,VdL,...
                            RonH,RonL,RLe,Le,NH,NL,Rtrp,Rtrs,Llkp,Llks,nn,mm,kin,ko,krin,kro,V2,V1);
                        arrPo(1,count) = -a;
                        arrPin(1,count) = -b;
                        arrITA(1,count) = round(b / a, 3);
                    end
                end
                plot(app.UIAxes,d_sweep,arrPin,d_sweep,arrPo);
                legend(app.UIAxes,{'Power HV','Power LV'},'Location','Best')
                xlabel(app.UIAxes,'unified phase shift');
                ylabel(app.UIAxes,'Power [W]');
                title(app.UIAxes,'Power vs. unified phase shift');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PowerDAB 1.0';
            app.MacroparametersLabel = uilabel(app.UIFigure);
            app.MacroparametersLabel.Text = 'Macro parameters';
            app.SemiconductorsLabel = uilabel(app.UIFigure);
            app.SemiconductorsLabel.Text = 'Semiconductors';
            app.InductorLabel = uilabel(app.UIFigure);
            app.InductorLabel.Text = 'Inductor';
            app.TransformerLabel = uilabel(app.UIFigure);
            app.TransformerLabel.Text = 'Transformer';
            app.DCHVVLabel = uilabel(app.UIFigure);
            app.DCHVVLabel.Text = 'DC HV [V]';
            app.DCLVVLabel = uilabel(app.UIFigure);
            app.DCLVVLabel.Text = 'DC LV [V]';
            app.SwitchingfreqHzLabel = uilabel(app.UIFigure);
            app.SwitchingfreqHzLabel.Text = {'Switching '; 'freq. [Hz]'};
            app.DeadtimesLabel = uilabel(app.UIFigure);
            app.DeadtimesLabel.Text = 'Dead time [s]';
            app.ReverseVdropHVVLabel = uilabel(app.UIFigure);
            app.ReverseVdropHVVLabel.Text = {'Reverse V-drop'; 'HV [V]'};
            app.ReverseVdropLVVLabel = uilabel(app.UIFigure);
            app.ReverseVdropLVVLabel.Text = {'Reverse V-drop'; 'LV [V]'};
            app.OnresistanceHVohmLabel = uilabel(app.UIFigure);
            app.OnresistanceHVohmLabel.Text = {'On-resistance'; 'HV [ohm]'};
            app.OnresistanceLVohmLabel = uilabel(app.UIFigure);
            app.OnresistanceLVohmLabel.Text = {'On-resistance'; 'LV [ohm]'};
            app.CossHVFLabel = uilabel(app.UIFigure);
            app.CossHVFLabel.Text = 'Coss HV [F]';
            app.CossLVFLabel = uilabel(app.UIFigure);
            app.CossLVFLabel.Text = 'Coss LV [F]';
            app.WindingresistanceohmLabel = uilabel(app.UIFigure);
            app.WindingresistanceohmLabel.Text = {'Winding'; 'resistance [ohm]'};
            app.InductanceHLabel = uilabel(app.UIFigure);
            app.InductanceHLabel.Text = 'Inductance [H]';
            app.TurnsHVLabel = uilabel(app.UIFigure);
            app.TurnsHVLabel.Text = 'Turns HV';
            app.TurnsLVLabel = uilabel(app.UIFigure);
            app.TurnsLVLabel.Text = 'Turns LV';
            app.HVwindingresistanceohmLabel = uilabel(app.UIFigure);
            app.HVwindingresistanceohmLabel.Text = {'HV winding'; 'resistance [ohm]'};
            app.LVwindingresistanceohmLabel = uilabel(app.UIFigure);
            app.LVwindingresistanceohmLabel.Text = {'LV winding'; 'resistance [ohm]'};
            app.HVwindingleakageinductanceHLabel = uilabel(app.UIFigure);
            app.HVwindingleakageinductanceHLabel.Text = {'HV winding'; 'leakage inductance [H]'};
            app.LVwindingleakageinductanceHLabel = uilabel(app.UIFigure);
            app.LVwindingleakageinductanceHLabel.Text = {'LV winding'; 'leakage inductance [H]'};
            app.NoofmodulesLabel = uilabel(app.UIFigure);
            app.NoofmodulesLabel.Text = 'No. of modules';
            app.SingleLabel = uilabel(app.UIFigure);
            app.SingleLabel.Text = 'Single';
            app.UnifiedphaseshiftLabel = uilabel(app.UIFigure);
            app.UnifiedphaseshiftLabel.Text = {'Unified'; 'phase shift'};
            app.HVPowerWLabel = uilabel(app.UIFigure);
            app.HVPowerWLabel.Text = 'HV Power [W]';
            app.LVPowerWLabel = uilabel(app.UIFigure);
            app.LVPowerWLabel.Text = 'LV Power [W]';
            app.TotallossWLabel = uilabel(app.UIFigure);
            app.TotallossWLabel.Text = 'Total loss [W]';
            app.EfficiencyLabel = uilabel(app.UIFigure);
            app.EfficiencyLabel.Text = 'Efficiency';
            app.ModularizationLabel = uilabel(app.UIFigure);
            app.ModularizationLabel.Text = 'Modularization';
            app.CalculationLabel = uilabel(app.UIFigure);
            app.CalculationLabel.Text = 'Calculation';
            app.CellconfigurationLabel = uilabel(app.UIFigure);
            app.CellconfigurationLabel.Text = 'Cell configuration';
            app.MultipleLabel = uilabel(app.UIFigure);
            app.MultipleLabel.Text = 'Multiple';
            app.RangeofunifiedphaseshiftLabel = uilabel(app.UIFigure);
            app.RangeofunifiedphaseshiftLabel.Text = 'Range of unified phase shift';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.StopLabel = uilabel(app.UIFigure);
            app.StopLabel.Text = 'Stop';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step';
            app.GeneratePlotButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'GeneratePlot';
            app.psDefinitionofmodulestructurescanbefoundinthepaperLabel = uilabel(app.UIFigure);
            app.psDefinitionofmodulestructurescanbefoundinthepaperLabel.Text = 'ps.Definition of module structures can be found in the paper';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Power vs. unified phase shift')
            xlabel(app.UIAxes, 'Unified phase shift')
            ylabel(app.UIAxes, 'Power [W]')
            app.VHEditFieldLabel = uilabel(app.UIFigure);
            app.VHEditFieldLabel.Visible = 'off';
            app.VHEditFieldLabel.Text = 'VH';
            app.VHEditField = uieditfield(app.UIFigure, 'numeric');
            app.VHEditField.Value = 300;
            app.VLEditFieldLabel = uilabel(app.UIFigure);
            app.VLEditFieldLabel.Visible = 'off';
            app.VLEditFieldLabel.Text = 'VL';
            app.VLEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLEditField.Value = 25;
            app.fsEditFieldLabel = uilabel(app.UIFigure);
            app.fsEditFieldLabel.Visible = 'off';
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.UIFigure, 'numeric');
            app.fsEditField.Value = 300000;
            app.TdEditFieldLabel = uilabel(app.UIFigure);
            app.TdEditFieldLabel.Visible = 'off';
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.UIFigure, 'numeric');
            app.TdEditField.Value = 7e-08;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Visible = 'off';
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 0.2;
            app.VdHEditFieldLabel = uilabel(app.UIFigure);
            app.VdHEditFieldLabel.Visible = 'off';
            app.VdHEditFieldLabel.Text = 'VdH';
            app.VdHEditField = uieditfield(app.UIFigure, 'numeric');
            app.VdHEditField.Value = 2;
            app.VdLEditFieldLabel = uilabel(app.UIFigure);
            app.VdLEditFieldLabel.Visible = 'off';
            app.VdLEditFieldLabel.Text = 'VdL';
            app.VdLEditField = uieditfield(app.UIFigure, 'numeric');
            app.VdLEditField.Value = 1;
            app.RonHEditFieldLabel = uilabel(app.UIFigure);
            app.RonHEditFieldLabel.Visible = 'off';
            app.RonHEditFieldLabel.Text = 'RonH';
            app.RonHEditField = uieditfield(app.UIFigure, 'numeric');
            app.RonHEditField.Value = 0.025;
            app.RonLEditFieldLabel = uilabel(app.UIFigure);
            app.RonLEditFieldLabel.Visible = 'off';
            app.RonLEditFieldLabel.Text = 'RonL';
            app.RonLEditField = uieditfield(app.UIFigure, 'numeric');
            app.RonLEditField.Value = 0.0018;
            app.RLeEditFieldLabel = uilabel(app.UIFigure);
            app.RLeEditFieldLabel.Visible = 'off';
            app.RLeEditFieldLabel.Text = 'RLe';
            app.RLeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RLeEditField.Value = 0.003;
            app.LeEditFieldLabel = uilabel(app.UIFigure);
            app.LeEditFieldLabel.Visible = 'off';
            app.LeEditFieldLabel.Text = 'Le';
            app.LeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeEditField.Value = 4.3e-07;
            app.NHEditFieldLabel = uilabel(app.UIFigure);
            app.NHEditFieldLabel.Visible = 'off';
            app.NHEditFieldLabel.Text = 'NH';
            app.NHEditField = uieditfield(app.UIFigure, 'numeric');
            app.NHEditField.Value = 8;
            app.NLEditFieldLabel = uilabel(app.UIFigure);
            app.NLEditFieldLabel.Visible = 'off';
            app.NLEditFieldLabel.Text = 'NL';
            app.NLEditField = uieditfield(app.UIFigure, 'numeric');
            app.NLEditField.Value = 2;
            app.RtrpEditFieldLabel = uilabel(app.UIFigure);
            app.RtrpEditFieldLabel.Visible = 'off';
            app.RtrpEditFieldLabel.Text = 'Rtrp';
            app.RtrpEditField = uieditfield(app.UIFigure, 'numeric');
            app.RtrpEditField.Value = 0.7;
            app.RtrsEditFieldLabel = uilabel(app.UIFigure);
            app.RtrsEditFieldLabel.Visible = 'off';
            app.RtrsEditFieldLabel.Text = 'Rtrs';
            app.RtrsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RtrsEditField.Value = 0.045;
            app.LlkpEditFieldLabel = uilabel(app.UIFigure);
            app.LlkpEditFieldLabel.Visible = 'off';
            app.LlkpEditFieldLabel.Text = 'Llkp';
            app.LlkpEditField = uieditfield(app.UIFigure, 'numeric');
            app.LlkpEditField.Value = 2.5e-06;
            app.LlksEditFieldLabel = uilabel(app.UIFigure);
            app.LlksEditFieldLabel.Visible = 'off';
            app.LlksEditFieldLabel.Text = 'Llks';
            app.LlksEditField = uieditfield(app.UIFigure, 'numeric');
            app.LlksEditField.Value = 1.1e-07;
            app.ModulestructureDropDownLabel = uilabel(app.UIFigure);
            app.ModulestructureDropDownLabel.Text = 'Module structure';
            app.ModulestructureDropDown = uidropdown(app.UIFigure);
            app.ModulestructureDropDown.Items = {'ISOP - 1', 'ISOP - 2', 'ISOP - 3', 'ISOP - 4', 'ISOP - 5', 'ISOP - 6', 'IPOS - 1', 'IPOS - 2', 'IPOS - 3', 'IPOS - 4', 'IPOS - 5', 'IPOS - 6', 'SS - 1', 'SS - 2', 'SS - 3', 'SS - 4', 'SS - 5', 'SS - 6', 'PP - 1', 'PP - 2', 'PP - 3', 'PP - 4', 'PP - 5', 'PP - 6'};
            app.ModulestructureDropDown.Value = 'ISOP - 3';
            app.NmEditFieldLabel = uilabel(app.UIFigure);
            app.NmEditFieldLabel.Visible = 'off';
            app.NmEditFieldLabel.Text = 'Nm';
            app.NmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmEditField.Value = 2;
            app.PHVEditFieldLabel = uilabel(app.UIFigure);
            app.PHVEditFieldLabel.Visible = 'off';
            app.PHVEditFieldLabel.Text = 'PHV';
            app.PHVEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLVEditFieldLabel = uilabel(app.UIFigure);
            app.PLVEditFieldLabel.Visible = 'off';
            app.PLVEditFieldLabel.Text = 'PLV';
            app.PLVEditField = uieditfield(app.UIFigure, 'numeric');
            app.CossHEditFieldLabel = uilabel(app.UIFigure);
            app.CossHEditFieldLabel.Visible = 'off';
            app.CossHEditFieldLabel.Text = 'CossH';
            app.CossHEditField = uieditfield(app.UIFigure, 'numeric');
            app.CossHEditField.Value = 2.84e-10;
            app.CossLEditFieldLabel = uilabel(app.UIFigure);
            app.CossLEditFieldLabel.Visible = 'off';
            app.CossLEditFieldLabel.Text = 'CossL';
            app.CossLEditField = uieditfield(app.UIFigure, 'numeric');
            app.CossLEditField.Value = 2.62e-09;
            app.lossEditFieldLabel = uilabel(app.UIFigure);
            app.lossEditFieldLabel.Visible = 'off';
            app.lossEditFieldLabel.Text = 'loss';
            app.lossEditField = uieditfield(app.UIFigure, 'numeric');
            app.etaEditFieldLabel = uilabel(app.UIFigure);
            app.etaEditFieldLabel.Visible = 'off';
            app.etaEditFieldLabel.Text = 'eta';
            app.etaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dbEditFieldLabel = uilabel(app.UIFigure);
            app.dbEditFieldLabel.Visible = 'off';
            app.dbEditFieldLabel.Text = 'db';
            app.dbEditField = uieditfield(app.UIFigure, 'numeric');
            app.dbEditField.Value = 0.08;
            app.dtEditFieldLabel = uilabel(app.UIFigure);
            app.dtEditFieldLabel.Visible = 'off';
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtEditField.Value = 0.3;
            app.dstepEditFieldLabel = uilabel(app.UIFigure);
            app.dstepEditFieldLabel.Visible = 'off';
            app.dstepEditFieldLabel.Text = 'dstep';
            app.dstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.dstepEditField.Value = 0.002;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerDAB_initial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end